classdef RoboticsAppGUIV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeometryInputsTab              matlab.ui.container.Tab
        CurrentRobotDetailTextArea     matlab.ui.control.TextArea
        CurrentRobotDetailTextAreaLabel  matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        NoteAvoidInitialRobotPositionatSingularityLabel  matlab.ui.control.Label
        WarningchangingvaluewillclearalljointdataLabel  matlab.ui.control.Label
        StatusNoRobotProvidedLabel     matlab.ui.control.Label
        GenerateRobotButton            matlab.ui.control.Button
        PleaseSelectJointPanel         matlab.ui.container.Panel
        OtherParametersPanel           matlab.ui.container.Panel
        InitialJointPositionEditField  matlab.ui.control.NumericEditField
        InitialJointPositionEditFieldLabel  matlab.ui.control.Label
        JointMinEditField              matlab.ui.control.NumericEditField
        JointMinEditFieldLabel         matlab.ui.control.Label
        JointMaxEditField              matlab.ui.control.NumericEditField
        JointMaxEditFieldLabel         matlab.ui.control.Label
        CoulombFrictionCoeffEditField  matlab.ui.control.NumericEditField
        CoulombFrictionCoeffLabel      matlab.ui.control.Label
        ViscousFrictionCoefEditField   matlab.ui.control.NumericEditField
        ViscousFrictionCoefEditFieldLabel  matlab.ui.control.Label
        Kr_iEditField                  matlab.ui.control.NumericEditField
        Kr_iEditFieldLabel             matlab.ui.control.Label
        MassPropertiesPanel            matlab.ui.container.Panel
        MotorPropertiesPanel           matlab.ui.container.Panel
        I_33EditField_2                matlab.ui.control.NumericEditField
        I_33EditField_2Label           matlab.ui.control.Label
        I_32EditField_2                matlab.ui.control.NumericEditField
        I_32EditField_2Label           matlab.ui.control.Label
        I_31EditField_2                matlab.ui.control.NumericEditField
        I_31EditField_2Label           matlab.ui.control.Label
        I_23EditField_2                matlab.ui.control.NumericEditField
        I_23EditField_2Label           matlab.ui.control.Label
        I_22EditField_2                matlab.ui.control.NumericEditField
        I_22EditField_2Label           matlab.ui.control.Label
        I_21EditField_2                matlab.ui.control.NumericEditField
        I_21EditField_2Label           matlab.ui.control.Label
        I_13EditField_2                matlab.ui.control.NumericEditField
        I_13EditField_2Label           matlab.ui.control.Label
        I_12EditField_2                matlab.ui.control.NumericEditField
        I_12EditField_2Label           matlab.ui.control.Label
        I_11EditField_2                matlab.ui.control.NumericEditField
        I_11EditField_2Label           matlab.ui.control.Label
        MassEditField_2                matlab.ui.control.NumericEditField
        MassEditField_2Label           matlab.ui.control.Label
        LinkPropertiesPanel            matlab.ui.container.Panel
        I_33EditField                  matlab.ui.control.NumericEditField
        I_33EditFieldLabel             matlab.ui.control.Label
        I_32EditField                  matlab.ui.control.NumericEditField
        I_32EditFieldLabel             matlab.ui.control.Label
        I_31EditField                  matlab.ui.control.NumericEditField
        I_31EditFieldLabel             matlab.ui.control.Label
        I_23EditField                  matlab.ui.control.NumericEditField
        I_23EditFieldLabel             matlab.ui.control.Label
        I_22EditField                  matlab.ui.control.NumericEditField
        I_22EditFieldLabel             matlab.ui.control.Label
        I_21EditField                  matlab.ui.control.NumericEditField
        I_21EditFieldLabel             matlab.ui.control.Label
        I_13EditField                  matlab.ui.control.NumericEditField
        I_13EditFieldLabel             matlab.ui.control.Label
        I_12EditField                  matlab.ui.control.NumericEditField
        I_12EditFieldLabel             matlab.ui.control.Label
        I_11EditField                  matlab.ui.control.NumericEditField
        I_11EditFieldLabel             matlab.ui.control.Label
        MassEditField                  matlab.ui.control.NumericEditField
        MassEditFieldLabel             matlab.ui.control.Label
        DHParametersPanel              matlab.ui.container.Panel
        Switch_2                       matlab.ui.control.Switch
        dEditField                     matlab.ui.control.NumericEditField
        dEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        ThetaEditFieldLabel            matlab.ui.control.Label
        AlphaEditField                 matlab.ui.control.NumericEditField
        AlphaEditFieldLabel            matlab.ui.control.Label
        SelectedJointDropDown          matlab.ui.control.DropDown
        SelectedJointLabel             matlab.ui.control.Label
        NumberofJointsSpinner          matlab.ui.control.Spinner
        NumberofJointsSpinnerLabel     matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZLabel                         matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        GravityComponentsLabel         matlab.ui.control.Label
        GeometryInputsLabel            matlab.ui.control.Label
        EoMsTab                        matlab.ui.container.Tab
        GenerateAnalyticalEquationsofMotionButton  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        JointDynamicsTab               matlab.ui.container.Tab
        TrajectoryErrorLable           matlab.ui.control.Label
        DynamicsGeneratorErrorLable    matlab.ui.control.Label
        GenerateJointDyanamicsButton   matlab.ui.control.Button
        TrajectoriesasafunctionoftimePanel  matlab.ui.container.Panel
        NotePleaseprovideposeintheZYZschemeLabel  matlab.ui.control.Label
        ActivatePsiCheckBox            matlab.ui.control.CheckBox
        ActivatePhiCheckBox            matlab.ui.control.CheckBox
        ActivateThetaCheckBox          matlab.ui.control.CheckBox
        ActivateZCheckBox              matlab.ui.control.CheckBox
        ActivateYCheckBox              matlab.ui.control.CheckBox
        ActivateXCheckBox              matlab.ui.control.CheckBox
        TimeStepSizeEditField          matlab.ui.control.NumericEditField
        TimeStepSizeEditFieldLabel     matlab.ui.control.Label
        FinalTimeEditField             matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel        matlab.ui.control.Label
        PsitEditField                  matlab.ui.control.EditField
        PsitEditFieldLabel             matlab.ui.control.Label
        PhitEditField                  matlab.ui.control.EditField
        PhitEditFieldLabel             matlab.ui.control.Label
        ThetatEditField                matlab.ui.control.EditField
        ThetatEditFieldLabel           matlab.ui.control.Label
        ZtEditField                    matlab.ui.control.EditField
        ZtEditFieldLabel               matlab.ui.control.Label
        YtEditField                    matlab.ui.control.EditField
        YtEditFieldLabel               matlab.ui.control.Label
        XtEditField                    matlab.ui.control.EditField
        XtEditFieldLabel               matlab.ui.control.Label
        JointDynamicsLabel             matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ComplianceControlTab           matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        GainsPanel_2                   matlab.ui.container.Panel
        KdPsiEditField_2               matlab.ui.control.NumericEditField
        KdPsiEditField_2Label          matlab.ui.control.Label
        KdThetaEditField_2             matlab.ui.control.NumericEditField
        KdThetaEditField_2Label        matlab.ui.control.Label
        KdPhiEditField_2               matlab.ui.control.NumericEditField
        KdPhiEditField_2Label          matlab.ui.control.Label
        KdzEditField                   matlab.ui.control.NumericEditField
        KdzEditFieldLabel              matlab.ui.control.Label
        KdyEditField                   matlab.ui.control.NumericEditField
        KdyEditFieldLabel              matlab.ui.control.Label
        KdxEditField                   matlab.ui.control.NumericEditField
        KdxEditFieldLabel              matlab.ui.control.Label
        KpPsiEditField_2               matlab.ui.control.NumericEditField
        KpPsiEditField_2Label          matlab.ui.control.Label
        KpThetaEditField_2             matlab.ui.control.NumericEditField
        KpThetaEditField_2Label        matlab.ui.control.Label
        KpPhiEditField_2               matlab.ui.control.NumericEditField
        KpPhiEditField_2Label          matlab.ui.control.Label
        KpzEditField                   matlab.ui.control.NumericEditField
        KpzEditFieldLabel              matlab.ui.control.Label
        KpyEditField                   matlab.ui.control.NumericEditField
        KpyEditFieldLabel              matlab.ui.control.Label
        KpxEditField                   matlab.ui.control.NumericEditField
        KpxEditFieldLabel              matlab.ui.control.Label
        DesiredMotionPanel             matlab.ui.container.Panel
        endeffectorframeinRPYframeLabel_2  matlab.ui.control.Label
        PsitEditField_3                matlab.ui.control.EditField
        PsitEditField_3Label           matlab.ui.control.Label
        PhitEditField_3                matlab.ui.control.EditField
        PhitEditField_3Label           matlab.ui.control.Label
        ThetatEditField_3              matlab.ui.control.EditField
        ThetatEditField_3Label         matlab.ui.control.Label
        ZtEditField_3                  matlab.ui.control.EditField
        ZtEditField_3Label             matlab.ui.control.Label
        YtEditField_3                  matlab.ui.control.EditField
        YtEditField_3Label             matlab.ui.control.Label
        XtEditField_3                  matlab.ui.control.EditField
        XtEditField_3Label             matlab.ui.control.Label
        ActivatePsiCheckBox_3          matlab.ui.control.CheckBox
        ActivatePhiCheckBox_3          matlab.ui.control.CheckBox
        ActivateThetaCheckBox_3        matlab.ui.control.CheckBox
        ActivateZCheckBox_3            matlab.ui.control.CheckBox
        ActivateYCheckBox_3            matlab.ui.control.CheckBox
        ActivateXCheckBox_3            matlab.ui.control.CheckBox
        StopTimeEditField              matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel         matlab.ui.control.Label
        FPsitEditField                 matlab.ui.control.EditField
        FPsitEditFieldLabel            matlab.ui.control.Label
        FThetatEditField               matlab.ui.control.EditField
        FThetatEditFieldLabel          matlab.ui.control.Label
        FPhitEditField                 matlab.ui.control.EditField
        FPhitEditFieldLabel            matlab.ui.control.Label
        FztEditField                   matlab.ui.control.EditField
        FztEditFieldLabel              matlab.ui.control.Label
        FytEditField                   matlab.ui.control.EditField
        FytEditFieldLabel              matlab.ui.control.Label
        FxtEditField                   matlab.ui.control.EditField
        FxtEditFieldLabel              matlab.ui.control.Label
        ComplianceControlLabel         matlab.ui.control.Label
        endeffectorframeinRPYframeLabel  matlab.ui.control.Label
        EndEffectorForceInputsLabel_2  matlab.ui.control.Label
        RunComplainceControlButton     matlab.ui.control.Button
        UIAxes3_4                      matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        ImpedanceControlTab            matlab.ui.container.Tab
        FPsitEditField_2               matlab.ui.control.EditField
        FPsitEditField_2Label          matlab.ui.control.Label
        GainsPanel                     matlab.ui.container.Panel
        MdPsiEditField                 matlab.ui.control.NumericEditField
        MdPsiEditFieldLabel            matlab.ui.control.Label
        MdThetaEditField               matlab.ui.control.NumericEditField
        MdThetaEditFieldLabel          matlab.ui.control.Label
        MdPhiEditField                 matlab.ui.control.NumericEditField
        MdPhiEditFieldLabel            matlab.ui.control.Label
        MdzEditField                   matlab.ui.control.NumericEditField
        MdzEditFieldLabel              matlab.ui.control.Label
        MdyEditField                   matlab.ui.control.NumericEditField
        MdyEditFieldLabel              matlab.ui.control.Label
        MdxEditField                   matlab.ui.control.NumericEditField
        MdxEditFieldLabel              matlab.ui.control.Label
        KdPsiEditField                 matlab.ui.control.NumericEditField
        KdPsiEditFieldLabel            matlab.ui.control.Label
        KdThetaEditField               matlab.ui.control.NumericEditField
        KdThetaEditFieldLabel          matlab.ui.control.Label
        KdPhiEditField                 matlab.ui.control.NumericEditField
        KdPhiEditFieldLabel            matlab.ui.control.Label
        KdzEditField_2                 matlab.ui.control.NumericEditField
        KdzEditField_2Label            matlab.ui.control.Label
        KdyEditField_2                 matlab.ui.control.NumericEditField
        KdyEditField_2Label            matlab.ui.control.Label
        KdxEditField_2                 matlab.ui.control.NumericEditField
        KdxEditField_2Label            matlab.ui.control.Label
        KpPsiEditField                 matlab.ui.control.NumericEditField
        KpPsiEditFieldLabel            matlab.ui.control.Label
        KpThetaEditField               matlab.ui.control.NumericEditField
        KpThetaEditFieldLabel          matlab.ui.control.Label
        KpPhiEditField                 matlab.ui.control.NumericEditField
        KpPhiEditFieldLabel            matlab.ui.control.Label
        KpzEditField_2                 matlab.ui.control.NumericEditField
        KpzEditField_2Label            matlab.ui.control.Label
        KpyEditField_2                 matlab.ui.control.NumericEditField
        KpyEditField_2Label            matlab.ui.control.Label
        KpxEditField_2                 matlab.ui.control.NumericEditField
        KpxEditField_2Label            matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FThetatEditField_2             matlab.ui.control.EditField
        FThetatEditField_2Label        matlab.ui.control.Label
        FPhitEditField_2               matlab.ui.control.EditField
        FPhitEditField_2Label          matlab.ui.control.Label
        FztEditField_2                 matlab.ui.control.EditField
        FztEditField_2Label            matlab.ui.control.Label
        FytEditField_2                 matlab.ui.control.EditField
        FytEditField_2Label            matlab.ui.control.Label
        FxtEditField_2                 matlab.ui.control.EditField
        FxtEditField_2Label            matlab.ui.control.Label
        TrajectoriesasafunctionoftimePanel_2  matlab.ui.container.Panel
        encountersasingularityLabel    matlab.ui.control.Label
        NoteSystemmaynotconvergeiftherobotLabel  matlab.ui.control.Label
        ActivatePsiCheckBox_2          matlab.ui.control.CheckBox
        ActivatePhiCheckBox_2          matlab.ui.control.CheckBox
        ActivateThetaCheckBox_2        matlab.ui.control.CheckBox
        ActivateZCheckBox_2            matlab.ui.control.CheckBox
        ActivateYCheckBox_2            matlab.ui.control.CheckBox
        ActivateXCheckBox_2            matlab.ui.control.CheckBox
        StopTimeEditField_2            matlab.ui.control.NumericEditField
        StopTimeEditField_2Label       matlab.ui.control.Label
        PleaseprovideposeintheRPYschemeLabel  matlab.ui.control.Label
        PsitEditField_2                matlab.ui.control.EditField
        PsitEditField_2Label           matlab.ui.control.Label
        PhitEditField_2                matlab.ui.control.EditField
        PhitEditField_2Label           matlab.ui.control.Label
        ThetatEditField_2              matlab.ui.control.EditField
        ThetatEditField_2Label         matlab.ui.control.Label
        ZtEditField_2                  matlab.ui.control.EditField
        ZtEditField_2Label             matlab.ui.control.Label
        YtEditField_2                  matlab.ui.control.EditField
        YtEditField_2Label             matlab.ui.control.Label
        XtEditField_2                  matlab.ui.control.EditField
        XtEditField_2Label             matlab.ui.control.Label
        ImpedanceControlLabel          matlab.ui.control.Label
        endeffectorframeinRPYframeLabel_3  matlab.ui.control.Label
        EndEffectorForceInputsLabel    matlab.ui.control.Label
        RunImpedanceControlButton      matlab.ui.control.Button
        UIAxes3_3                      matlab.ui.control.UIAxes
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        HelpTab                        matlab.ui.container.Tab
        TextArea_3                     matlab.ui.control.TextArea
        Hyperlink                      matlab.ui.control.Hyperlink
    end

    
    properties (Access = private)
       
    end
    
    properties (Access = public)
    %Define all public variables here
        
        NumJoints = 1; % number of joints
        GravityVector = [0, 0, -9.81]; %gravity vector defined in base frame

        %DH parameters and other joint variables --> i represents joint num
        alpha_i = [];
        theta_i = [];
        a_i = [];
        d_i = [];

        JointType = {}; %prismatic or revolute
        kr_i = []; %gear ratio
        ViscousFrictionCoeff_i = [];
        CoulombFrictionCoeff_i = [];
        I_L_i = []; %moment of inertia for link
        I_m_i = []; %moment of inertia for link
        MotorMass_i = [];
        LinkMass_i = [];
        JointParameterMin_i= [];
        JointParameterMax_i= [];
        q_0_i = []; %initial Joint parameter value

        %compliance control matrices
        Kp = [];
        Kd = [];
        Md = [];
        Xd;
        
        %DOFs we care about for impedecne control
        activeDOF = ones(6,1);
        numActiveDofs = 0;

        %indicates which joint is currently active to be edited in the
        %inputs table
        SelectedJoint;
        
        %joint force vector in end effector frame
        F_ex = zeros(6,1);
        ForcesInBaseFrame = true;% variable for switch showing if the force is defined in the base frame

        ManipulatorSerialLinkObject;
        
        %complete analytical Equation
        FullAnalyticalEquation;

        StopTime= 10;

        G_func;
        n_func;
        B_func;
        Calc_He;
        Calc_Xd;
    end
    
    methods (Access = private)
        
        function UpdateShownJointParameterFields(app)
            %load previously entered values
            app.AlphaEditField.Value = (app.alpha_i(str2num(app.SelectedJoint)));
            app.ThetaEditField.Value = (app.theta_i(str2num(app.SelectedJoint)));
            app.aEditField.Value= (app.a_i(str2num(app.SelectedJoint)));
            app.dEditField.Value= (app.d_i(str2num(app.SelectedJoint)));
            app.JointMaxEditField.Value= (app.JointParameterMax_i(str2num(app.SelectedJoint)));
            app.JointMinEditField.Value= (app.JointParameterMin_i(str2num(app.SelectedJoint)));
            app.ViscousFrictionCoefEditField.Value = app.CoulombFrictionCoeff_i(str2num(app.SelectedJoint));
            app.CoulombFrictionCoeffEditField.Value= app.ViscousFrictionCoeff_i(str2num(app.SelectedJoint));
            app.Kr_iEditField.Value = app.kr_i(str2num(app.SelectedJoint));
            app.MassEditField.Value = app.LinkMass_i(str2num(app.SelectedJoint));
            app.MassEditField_2.Value= app.MotorMass_i(str2num(app.SelectedJoint));
    
            %link moment of inertia fields:
            app.I_11EditField.Value = app.I_L_i(1,1,str2num(app.SelectedJoint));
            app.I_12EditField.Value = app.I_L_i(1,2,str2num(app.SelectedJoint));
            app.I_13EditField.Value = app.I_L_i(1,3,str2num(app.SelectedJoint));
            app.I_21EditField.Value = app.I_L_i(2,1,str2num(app.SelectedJoint));
            app.I_22EditField.Value = app.I_L_i(2,2,str2num(app.SelectedJoint));
            app.I_23EditField.Value = app.I_L_i(2,3,str2num(app.SelectedJoint));
            app.I_31EditField.Value = app.I_L_i(3,1,str2num(app.SelectedJoint));
            app.I_32EditField.Value = app.I_L_i(3,2,str2num(app.SelectedJoint));
            app.I_33EditField.Value = app.I_L_i(3,3,str2num(app.SelectedJoint));
    
            %motor moment of inertia fields:
            app.I_11EditField_2.Value = app.I_m_i(1,1,str2num(app.SelectedJoint));
            app.I_12EditField_2.Value = app.I_m_i(1,2,str2num(app.SelectedJoint));
            app.I_13EditField_2.Value = app.I_m_i(1,3,str2num(app.SelectedJoint));
            app.I_21EditField_2.Value = app.I_m_i(2,1,str2num(app.SelectedJoint));
            app.I_22EditField_2.Value = app.I_m_i(2,2,str2num(app.SelectedJoint));
            app.I_23EditField_2.Value = app.I_m_i(2,3,str2num(app.SelectedJoint));
            app.I_31EditField_2.Value = app.I_m_i(3,1,str2num(app.SelectedJoint));  
            app.I_32EditField_2.Value = app.I_m_i(3,2,str2num(app.SelectedJoint));
            app.I_33EditField_2.Value = app.I_m_i(3,3,str2num(app.SelectedJoint));
            app.InitialJointPositionEditField.Value = app.q_0_i(str2num(app.SelectedJoint));

            %set switch state for joint type
            if strcmp(app.JointType(str2num(app.SelectedJoint)),"Revolute")
                app.Switch_2.Value = 'Revolute';
            else
                app.Switch_2.Value = 'Prismatic';
            end
        end
        
    end
    
    methods (Access = public)
        
        function [robot] = make_robot(app, DH, OPTIONS)
            %MAKE_ROBOT Constructs a SerialLink Object
            %   Provide the DH parameters and options for kinematics and dynamics
            %   DH Input
            %       DH = [d theta a alpha];
            %       d, theta, a, alpha are nx1 arrays
            %       DH is an nx4 matrix
            % 
            %   OPTIONS
            %   see SerialLink documentation for details about input format
            %
                % Validate Input
            dhdims = size(DH)';
            if(dhdims(2) ~= 4)
                ME = MException('ikin:typeError', ...
                    'DH Parameters are in incorrect format. Expected nx5 Matrix');
                throw(ME)
            end
        
            numLinks = dhdims(1);
            fprintf('\nNumber of Links: %d', numLinks)
        
            % Create Links
            L = cell(numLinks, 1);
            for i = 1:numLinks
                % Collect Inputs
                dh = DH(i,:);
                O = OPTIONS.offset(i);
                qlim = OPTIONS.qlims{i};
                isRevolute = OPTIONS.isRevolute(i);
                I = OPTIONS.inertia{i};
                r = OPTIONS.centerOfMass{i};
                m = OPTIONS.mass(i);
                G = OPTIONS.gearRatio(i);
                B = OPTIONS.jointFric(i);
                Jm = OPTIONS.motorInertia(i);
                Tc = OPTIONS.coulombFric{i};
        
                % Create Link Object
                if(isRevolute)
        %             fprintf('\n\tLink %d is Revolute', i);
                    L{i} = Revolute('d', dh(1), 'a', dh(3), 'alpha', dh(4), 'offset', O, 'I', I, 'r', r, 'm', m, 'G', G, 'B', B, 'Jm', Jm, 'Tc', Tc);
                else
        %             fprintf('\n\tLink %d is Prismatic', i);
                    L{i} = Prismatic('theta', dh(2), 'a', dh(3), 'alpha', dh(4), 'offset', O, 'qlim', qlim, 'I', I, 'r', r, 'm', m, 'G', G, 'B', B, 'Jm', Jm, 'Tc', Tc);
                end
        %         Display link dynamics and kinematics
        %         L{i}.dyn
                end
            
                % Create Serial Robot
                R = SerialLink(horzcat(L{:}));
            
                % Create workspace
                SIZE = OPTIONS.workspace;
                R.name = 'robot';
                R.plotopt = {'workspace' [-SIZE,SIZE,-SIZE,SIZE,-SIZE,SIZE]};
                R.gravity = OPTIONS.gravity;
            
                robot = R;
        end
        
        function R = EulerAnglesRotationMatrix(app, EulerAnglesName, Angle1, Angle2, Angle3)
            if(strcmpi(EulerAnglesName, 'zyz'))
                R = ElementaryRoatation(app,"z", Angle1)*ElementaryRoatation(app,"y", Angle2)*ElementaryRoatation(app,"z", Angle3);
            elseif(strcmpi(EulerAnglesName, 'rpy'))
                R = ElementaryRoatation(app,"z", Angle3)*ElementaryRoatation(app,"y", Angle2)*ElementaryRoatation(app,"x", Angle1);
            else
                error("Euler angles name not recognized")
            end
         end

        function R = ElementaryRoatation(app, AxisName, Angle)
            if(strcmpi(AxisName, 'x'))
                R = [1 0 0; 0 cos(Angle) -sin(Angle); 0 sin(Angle) cos(Angle)];
            elseif(strcmpi(AxisName, 'y'))
                R = [cos(Angle) 0 sin(Angle); 0 1 0; -sin(Angle) 0 cos(Angle)];
            elseif(strcmpi(AxisName, 'z'))
                R = [cos(Angle) -sin(Angle), 0; sin(Angle) cos(Angle), 0; 0 0 1];
            else
                error("Please enter valid Axis Name")
            end
        end

        function [FullEquation] = GenerateAnalyticalEquations(app, numJoints,g_unitVector, JointTypes, dh_params)

        %JointTypes--> 0 == revolute, 1== prismatic
        
        %lets generate some symbolic symbols some parameters
            syms g t
            % syms q(t) [numJoints,1]
            q =sym('q', [numJoints,1]);
            % dq = diff(q,t)
            % ddq = diff(dq,t)
            ddq =sym('ddq', [numJoints,1]);
            dq =sym('dq', [numJoints,1]);
            I_Lzz_i =sym("I_Lzz_i", [3,3,numJoints]);
            I_mzz_i =sym("I_mzz_i", [3,3,numJoints]);
            Kr =sym("Kr", [numJoints,1]);
            mm =sym("mm", [numJoints,1]);
            ml =sym("ml", [numJoints,1]);
            Fv_diags =sym("Fv", [numJoints,1]);
            Fv = diag(Fv_diags);
            Fs_diags =sym("Fs", [numJoints,1]);
            Fs= diag(Fs_diags);
            tau =sym("tau", [numJoints,1]);
            h_e =sym("h_e", [6,1]);
        
            g_o = g*g_unitVector;%gravity vector
        
            %convert DH params to vectors
            a = dh_params(:,1);
            alpha = dh_params(:,2);
            d=dh_params(:,3);
            theta = dh_params(:,4); 
        
        %declaring some vectors. Each column if for a different i indice
        %i starts at 0
            T_o_i = eye(4);
            z = cell(1,numJoints+1);
            P_i = cell(1,numJoints+1);
            P_L = cell(1,numJoints+1);
        
            z{1}= T_o_i(1:3,3);
            P_i{1}= T_o_i(1:3,4);
            P_L{1}= T_o_i(1:3,4);
        
            R_Li = cell(1,numJoints+1);
            R_mi = cell(1,numJoints+1);
        
        %find the P_i, R_mi, R_Li and z vector for each joint using the homogeneous
        %transformation matrix
            for i = 1:numJoints
                T{i} = [cos(theta(i)) -sin(theta(i))*cos(alpha(i)) sin(theta(i))*sin(alpha(i)) a(i)*cos(theta(i));
                    sin(theta(i)) cos(theta(i))*cos(alpha(i)) -cos(theta(i))*sin(alpha(i)) a(i)*sin(theta(i));
                    0 sin(alpha(i)) cos(alpha(i)) d(i);
                    0 0 0 1];
            
                R_mi{i} =T_o_i(1:3,1:3); %assuming motor is at joint i-1
            
                T_o_i = T_o_i * T{i};
                T_o_i = simplify(T_o_i);
            
                z{i+1} = T_o_i(1:3,3);
                P_i{i+1}= T_o_i(1:3,4);
        
                %Calcuate P_L for i = 0 to numJoints as the midpoint between p_i
                %and p_i+1
                P_L{i+1} = (P_i{i} +P_i{i+1})/2;
            
                R_Li{i} =T_o_i(1:3,1:3);
            
            end

            
        %convert cells to symbolic matricies
            z = cell2sym(z);
            P_i = cell2sym(P_i);
            P_L = cell2sym(P_L);
        
        %calulate the link jacobians
            for i = 1:numJoints
                for j = 1:numJoints
                    
                    if(JointTypes(i) ==0)  
                        %Revolute Case:
                        if(j<i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jp_m{j} = cross(z(:,j), (P_L(:,i) - P_i(:,j)) );
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
            
                    else %prismatic case
                        if(j<i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jp_m{j} = z(:,j);
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
                        
                    end
                    
                end
                %store jacobians as cells
                Jp_Li{i} = [jp_L{:}];
                Jo_Li{i} = [jo_L{:}];
                Jp_mi{i} = [jp_m{:}];
                Jo_mi{i} = [jo_m{:}];
            end
        
        %some code in case we need to visualized the jacobians
            % Jp_Li_v = cell2sym(Jp_Li);
            % Jo_Li_v = cell2sym(Jo_Li)
            % Jp_mi_v = cell2sym(Jp_mi)
            % Jo_mi_v = cell2sym(Jo_mi)
        
        
        %calculate Arm Geometric Jacobian which takes us to the end effector
            for i = 1:numJoints
                if(JointTypes(i) ==0)  
                    %Revolute Case:
                    J_i{i} = [cross(z(:,i), (T_o_i(1:3,4) - P_i(:,i)));z(:,i)];
                  
                else
                    %prismatic case
                    J_i{i} = [z(:,i); 0;0;0];
                end
            end
        
        %combine all jacobian colunmns
            J = [J_i{:}];
        
        %calculate B
            B = zeros(numJoints);
            for i = 1:numJoints
                
                R_Li_i =cell2sym(R_Li(i));
                R_mi_i =cell2sym(R_mi(i));
            
                Jp_li_i = cell2sym(Jp_Li(i));
                Jo_li_i = cell2sym(Jo_Li(i));
                Jp_mi_i = cell2sym(Jp_mi(i));
                Jo_mi_i = cell2sym(Jo_mi(i));
            
                B= B + ml(i)*(Jp_li_i.')*Jp_li_i +Jo_li_i.'* R_Li_i*I_Lzz_i(:,:,i)*(R_Li_i.')*Jo_li_i + mm(i)*(Jp_mi_i.')*Jp_mi_i + Jo_mi_i.'*R_mi_i*I_mzz_i(:,:,i)* (R_mi_i.')*Jo_mi_i;
            end
            
            B = simplify(B);
            %matlabFunction(Beq,"File","Bfunction") %create function with B
        
        %calculate C_ijk
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C_ijk(i,j,k) = (1/2)*(diff(B(i,j),q(k)) + diff(B(i,k),q(j)) + diff(B(j,k),q(i)));
                        C_ijk(i,j,k)=simplify((C_ijk(i,j,k)));
                    end
                end
            end
        
        %calculate C_ij
            C = repmat({0},numJoints,numJoints);
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C(i,j) = sym2cell(cell2sym(C(i,j)) + C_ijk(i,j,k)*dq(k));
                    end
                end
            end
            
            C=simplify(cell2sym(C));
            % C_ijk=simplify((C_ijk));
        
        %calculate g_i
            g_i=repmat({0}, numJoints,1);
            for i =1:numJoints
                for j=1:numJoints
                    Jp_li_j = cell2sym(Jp_Li(i));
                    Jp_mi_j = cell2sym(Jp_mi(i));
                   
                    g_i{i} =  g_i{i} - (ml(j)*g_o.'*Jp_li_j(:,j) + mm(j)*g_o.'*Jp_mi_j(:,j)) ;
                end
            end
            
            g_i = simplify(cell2sym(g_i));
        
            %build the full dynamics questions
            FullEquation = tau - (J.')*h_e == B*ddq+C*dq+Fv*dq+Fs*sign(dq)+g_i;
            
            FullEquation=simplify(FullEquation);
        end
    
        function [] = GenerateDynamicFunctions(app)
           numJoints = app.NumJoints;
           g_unitVector = (app.GravityVector/norm(app.GravityVector)).';
           q =sym('q', [app.NumJoints,1]);
           Symbolic_a =sym('a', [app.NumJoints,1]);
           Symbolic_d =sym('d', [app.NumJoints,1]);
           Symbolic_theta =sym('theta', [app.NumJoints,1]);

           JointTypes = ones(1, app.NumJoints);
           for i = 1:app.NumJoints
                %set joint type
                if(strcmp(app.JointType(i),"Revolute"))
                    JointTypes(i) = 0;
                    Symbolic_theta(i) = q(i);
                else
                    Symbolic_d(i) = q(i);
                end

                if(app.a_i ==0)
                    Symbolic_a(i) = 0;
                end

                  if((app.d_i == 0) & (JointTypes(i) == 0 ))
                    Symbolic_d(i) = 0;
                  end

                if((app.theta_i) == 0 & (JointTypes(i) == 1))
                    Symbolic_theta(i) = 0;
                end
           end


           dh_params = [Symbolic_a, app.alpha_i, Symbolic_d, Symbolic_theta];
           %JointTypes--> 0 == revolute, 1== prismatic
           
        
            %lets generate some symbolic symbols some parameters
            syms g
            ddq =sym('ddq', [numJoints,1]);
            dq =sym('dq', [numJoints,1]);
            I_Lzz_i =sym("I_Lzz_i", [3,3,numJoints]);
            I_mzz_i =sym("I_mzz_i", [3,3,numJoints]);
            Kr =sym("Kr", [numJoints,1]);
            mm =sym("mm", [numJoints,1]);
            ml =sym("ml", [numJoints,1]);
            Fv_diags =sym("Fv", [numJoints,1]);
            Fv = diag(Fv_diags);
            Fs_diags =sym("Fs", [numJoints,1]);
            Fs= diag(Fs_diags);
            tau =sym("tau", [numJoints,1]);
            h_e =sym("h_e", [6,1]);
        
            g_o = g*g_unitVector;%gravity vector
        
            %convert DH params to vectors
            a = dh_params(:,1);
            alpha = dh_params(:,2);
            d=dh_params(:,3);
            theta = dh_params(:,4); 
        
        %declaring some vectors. Each column if for a different i indice
        %i starts at 0
            T_o_i = eye(4);
            z = cell(1,numJoints+1);
            P_i = cell(1,numJoints+1);
            P_L = cell(1,numJoints+1);
        
            z{1}= T_o_i(1:3,3);
            P_i{1}= T_o_i(1:3,4);
            P_L{1}= T_o_i(1:3,4);
        
            R_Li = cell(1,numJoints+1);
            R_mi = cell(1,numJoints+1);
        
        %find the P_i, R_mi, R_Li and z vector for each joint using the homogeneous
        %transformation matrix
            for i = 1:numJoints
                T{i} = [cos(theta(i)) -sin(theta(i))*cos(alpha(i)) sin(theta(i))*sin(alpha(i)) a(i)*cos(theta(i));
                    sin(theta(i)) cos(theta(i))*cos(alpha(i)) -cos(theta(i))*sin(alpha(i)) a(i)*sin(theta(i));
                    0 sin(alpha(i)) cos(alpha(i)) d(i);
                    0 0 0 1];
            
                R_mi{i} =T_o_i(1:3,1:3); %assuming motor is at joint i-1
            
                T_o_i = T_o_i * T{i};
                T_o_i = simplify(T_o_i);
            
                z{i+1} = T_o_i(1:3,3);
                P_i{i+1}= T_o_i(1:3,4);
        
                %Calcuate P_L for i = 0 to numJoints as the midpoint between p_i
                %and p_i+1
                P_L{i+1} = (P_i{i} +P_i{i+1})/2;
            
                R_Li{i} =T_o_i(1:3,1:3);
            
            end
        
        %convert cells to symbolic matricies
            z = cell2sym(z);
            P_i = cell2sym(P_i);
            P_L = cell2sym(P_L);
        
        %calulate the link jacobians
            for i = 1:numJoints
                for j = 1:numJoints
                    
                    if(JointTypes(i) ==0)  
                        %Revolute Case:
                        if(j<i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jp_m{j} = cross(z(:,j), (P_L(:,i) - P_i(:,j)) );
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = cross(z(:,j), (P_L(:,i+1) - P_i(:,j)) );
                            jo_L{j} = z(:,j);
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
            
                    else %prismatic case
                        if(j<i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jp_m{j} = z(:,j);
                            jo_m{j} = jo_L{j} ;
                        elseif(j==i)
                            jp_L{j} = z(:,j);
                            jo_L{j} = [0;0;0];
                            jo_m{j} =Kr(i)*jo_L{j} ;
                            jp_m{j} = [0;0;0];
                        else
                            jp_L{j} = [0;0;0];
                            jo_L{j} = [0;0;0];
                            jp_m{j} = [0;0;0];
                            jo_m{j} = [0;0;0];
                        end
                        
                    end
                    
                end
                %store jacobians as cells
                Jp_Li{i} = [jp_L{:}];
                Jo_Li{i} = [jo_L{:}];
                Jp_mi{i} = [jp_m{:}];
                Jo_mi{i} = [jo_m{:}];
            end
        
        %some code in case we need to visualized the jacobians
            % Jp_Li_v = cell2sym(Jp_Li);
            % Jo_Li_v = cell2sym(Jo_Li)
            % Jp_mi_v = cell2sym(Jp_mi)
            % Jo_mi_v = cell2sym(Jo_mi)
        
        
        %calculate Arm Geometric Jacobian which takes us to the end effector
            for i = 1:numJoints
                if(JointTypes(i) ==0)  
                    %Revolute Case:
                    J_i{i} = [cross(z(:,i), (T_o_i(1:3,4) - P_i(:,i)));z(:,i)];
                  
                else
                    %prismatic case
                    J_i{i} = [z(:,i); 0;0;0];
                end
            end
        
        %combine all jacobian colunmns
            J = [J_i{:}];
        
        %calculate B
            B = zeros(numJoints);
            for i = 1:numJoints
                
                R_Li_i =cell2sym(R_Li(i));
                R_mi_i =cell2sym(R_mi(i));
            
                Jp_li_i = cell2sym(Jp_Li(i));
                Jo_li_i = cell2sym(Jo_Li(i));
                Jp_mi_i = cell2sym(Jp_mi(i));
                Jo_mi_i = cell2sym(Jo_mi(i));
            
                B= B + ml(i)*(Jp_li_i.')*Jp_li_i +Jo_li_i.'* R_Li_i*I_Lzz_i(:,:,i)*(R_Li_i.')*Jo_li_i + mm(i)*(Jp_mi_i.')*Jp_mi_i + Jo_mi_i.'*R_mi_i*I_mzz_i(:,:,i)* (R_mi_i.')*Jo_mi_i;
            end
            
            B = simplify(B);
            %matlabFunction(Beq,"File","Bfunction") %create function with B

            Beq = subs(B, [Kr,mm,ml,a,alpha,d,theta],[app.kr_i,app.MotorMass_i,app.LinkMass_i,app.a_i,app.alpha_i, app.d_i,app.theta_i]);
            Beq = subs(Beq,I_mzz_i, app.I_m_i);
            Beq = (subs(Beq,I_Lzz_i, app.I_L_i));
            app.B_func = matlabFunction(Beq ,"Vars",[q]);
            
        %calculate C_ijk
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C_ijk(i,j,k) = (1/2)*(diff(B(i,j),q(k)) + diff(B(i,k),q(j)) + diff(B(j,k),q(i)));
                        C_ijk(i,j,k)=simplify((C_ijk(i,j,k)));
                    end
                end
            end
        
        %calculate C_ij
            C = repmat({0},numJoints,numJoints);
            for i = 1:numJoints
                for j = 1:numJoints
                    for k = 1:numJoints
                        C(i,j) = sym2cell(cell2sym(C(i,j)) + C_ijk(i,j,k)*dq(k));
                    end
                end
            end
            
            C=simplify(cell2sym(C));
            C_ijk=simplify((C_ijk));
        
        
        %calculate g_i
            g_i=repmat({0}, numJoints,1);
            for i =1:numJoints
                for j=1:numJoints
                    Jp_li_j = cell2sym(Jp_Li(i));
                    Jp_mi_j = cell2sym(Jp_mi(i));
                   
                    g_i{i} =  g_i{i} - (ml(j)*g_o.'*Jp_li_j(:,j) + mm(j)*g_o.'*Jp_mi_j(:,j)) ;
                end
            end
            
            g_i = simplify(cell2sym(g_i));

            Geq = subs(g_i,[Kr,mm,ml,a,alpha,d,theta],[ app.kr_i,app.MotorMass_i, app.LinkMass_i,app.a_i, app.alpha_i, app.d_i, app.theta_i]);
            Geq = subs(Geq,g,norm(app.GravityVector));
            app.G_func = matlabFunction(Geq,"Vars",[q]);

            n = C*dq+Fv*dq+Fs*sign(dq)+g_i;
            n = subs(n, [Kr,mm,ml,Fv_diags,Fs_diags,a,alpha,d,theta],[app.kr_i, app.MotorMass_i, app.LinkMass_i, app.ViscousFrictionCoeff_i, app.CoulombFrictionCoeff_i, app.a_i, app.alpha_i, app.d_i, app.theta_i]);
            n = subs(n,I_mzz_i, app.I_m_i);
            n = subs(n,I_Lzz_i, app.I_L_i);
            n = subs(n,g,norm(app.GravityVector));
            app.n_func = matlabFunction(n,"Vars",{q,dq});

        end

        function [B] = Generate_B(app, q_inp)
            B = app.B_func(q_inp);
        end

        function [n] = Generate_n(app, q_inp,q_dot_inp)
            n = app.n_func(q_inp,q_dot_inp);
        end

        function [G] = Generate_G(app, q_inp)
            G = app.G_func(q_inp);
        end

        function [Xe] = forwardKinematics(app, q)
            %calculates forward kinematics
            Te = app.ManipulatorSerialLinkObject.fkine(q);
            
            %Note that end effector is in rpy
            Xe = [transl(Te)';
                  tr2rpy(Te)'];
        end
        
        function [Ja] = GenerateAnlyticalJacobian(app, q)
            %returns analytical jacobian in terms of RPY angles
            Ja = app.ManipulatorSerialLinkObject.jacob0(q','rpy');
        end

        function [J] = GenerateGeometricJacobian(app, q)
            %returns geomtric jacobian
            J = app.ManipulatorSerialLinkObject.jacob0(q);
        end

        function [Ja_PseudoInv] = GenerateAnalyticalJacobianPseudoInv(app, q)
            %returns analytical jacobian in terms of RPY angles
            Ja = app.ManipulatorSerialLinkObject.jacob0(q','rpy');
            
            Ja = TrimUnusedDofs_rows(app,Ja);
            Ja_PseudoInv= pinv(Ja);
           

            % Ja = app.ManipulatorSerialLinkObject.jacob0(zeros(app.NumJoints,1),'rpy')
            % Ja = TrimUnusedDofs_rows(app,Ja)
            % Ja_PInv= pinv(Ja)
        end
    
        function [output] = TrimUnusedDofs_rows(app, input)
            output = input;
            for i = 6:-1:1
                if app.activeDOF(i) ==0
                    output(i,:) =[];
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: NumberofJointsSpinner
        function NumberofJointsSpinnerValueChanged(app, event)
            value = app.NumberofJointsSpinner.Value;

            %set the number of joints
            app.NumJoints = value;

            %have drop down selecting joint show all joint options
            app.SelectedJointDropDown.Items = string(1:app.NumJoints);
            
            %reset selected joint
            app.SelectedJoint = "1"; 

            %set length of all joint parameters
            for k = 1:app.NumJoints
                
                app.JointType(k) = {'Revolute'};
            end

            app.alpha_i = zeros(app.NumJoints, 1);
            app.theta_i =  zeros(app.NumJoints, 1);
            app.a_i =  zeros(app.NumJoints, 1);
            app.d_i =  zeros(app.NumJoints, 1);
            app.kr_i = ones(app.NumJoints, 1);
        
            app.ViscousFrictionCoeff_i = zeros(app.NumJoints, 1);
            app.CoulombFrictionCoeff_i = zeros(app.NumJoints, 1);
            app.MotorMass_i = ones(app.NumJoints, 1);
            app.LinkMass_i = ones(app.NumJoints, 1);
            app.I_L_i = zeros(3, 3, app.NumJoints); %(row, col, i)
            app.I_m_i = zeros(3, 3, app.NumJoints); %(row, col, i)
            
            for i = 1:app.NumJoints
                app.I_L_i(:,:,i) = eye(3);
                app.I_m_i(:,:,i) = eye(3);
            end

            app.JointParameterMin_i= zeros(app.NumJoints, 1);
            app.JointParameterMax_i= zeros(app.NumJoints, 1);
            app.q_0_i = ones(app.NumJoints, 1)*1;
            
            %update panel showing which joint is selected
            app.PleaseSelectJointPanel.Title = strcat('Joint Number:', num2str(app.SelectedJoint));

            %update shown joint parameters 
            UpdateShownJointParameterFields(app);
        end

        % Value changed function: SelectedJointDropDown
        function SelectedJointDropDownValueChanged(app, event)
            value = app.SelectedJointDropDown.Value;

            %update selected joint
            app.SelectedJoint = value; 
          
            %update panel showing which joint is selected
            app.PleaseSelectJointPanel.Title = strcat('Joint Number:', num2str(value));
            
            %load previously entered values
            UpdateShownJointParameterFields(app);
        
        end

        % Value changed function: ViscousFrictionCoefEditField
        function ViscousFrictionCoefEditFieldValueChanged(app, event)
            value = app.ViscousFrictionCoefEditField.Value;
            app.CoulombFrictionCoeff_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: CoulombFrictionCoeffEditField
        function CoulombFrictionCoeffEditFieldValueChanged(app, event)
            value = app.CoulombFrictionCoeffEditField.Value;
            app.ViscousFrictionCoeff_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: Kr_iEditField
        function Kr_iEditFieldValueChanged(app, event)
            value = app.Kr_iEditField.Value;
            app.kr_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.GravityVector(1) = value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.GravityVector(2) = value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.GravityVector(3) = value;
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            %link mass
            value = app.MassEditField.Value;
            app.LinkMass_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: MassEditField_2
        function MassEditField_2ValueChanged(app, event)
            %motor mass
            value = app.MassEditField_2.Value;
            app.MotorMass_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_11EditField
        function I_11EditFieldValueChanged(app, event)
            value = app.I_11EditField.Value;
            app.I_L_i(1,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_12EditField
        function I_12EditFieldValueChanged(app, event)
            value = app.I_12EditField.Value;
            app.I_L_i(1,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_13EditField
        function I_13EditFieldValueChanged(app, event)
            value = app.I_13EditField.Value;
            app.I_L_i(1,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_21EditField
        function I_21EditFieldValueChanged(app, event)
            value = app.I_21EditField.Value;
            app.I_L_i(2,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_22EditField
        function I_22EditFieldValueChanged(app, event)
            value = app.I_22EditField.Value;
            app.I_L_i(2,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_23EditField
        function I_23EditFieldValueChanged(app, event)
            value = app.I_23EditField.Value;
            app.I_L_i(2,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_31EditField
        function I_31EditFieldValueChanged(app, event)
            value = app.I_31EditField.Value;
            app.I_L_i(3,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_32EditField
        function I_32EditFieldValueChanged(app, event)
            value = app.I_32EditField.Value;
            app.I_L_i(3,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_33EditField
        function I_33EditFieldValueChanged(app, event)
            value = app.I_33EditField.Value;
            app.I_L_i(3,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_11EditField_2
        function I_11EditField_2ValueChanged(app, event)
            value = app.I_11EditField_2.Value;
            app.I_m_i(1,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_12EditField_2
        function I_12EditField_2ValueChanged(app, event)
            value = app.I_12EditField_2.Value;
            app.I_m_i(1,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_13EditField_2
        function I_13EditField_2ValueChanged(app, event)
            value = app.I_13EditField_2.Value;
            app.I_m_i(1,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_21EditField_2
        function I_21EditField_2ValueChanged(app, event)
            value = app.I_21EditField_2.Value;
            app.I_m_i(2,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_22EditField_2
        function I_22EditField_2ValueChanged(app, event)
            value = app.I_22EditField_2.Value;
            app.I_m_i(2,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_23EditField_2
        function I_23EditField_2ValueChanged(app, event)
            value = app.I_23EditField_2.Value;
            app.I_m_i(2,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_31EditField_2
        function I_31EditField_2ValueChanged(app, event)
            value = app.I_31EditField_2.Value;
            app.I_m_i(3,1,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_32EditField_2
        function I_32EditField_2ValueChanged(app, event)
            value = app.I_32EditField_2.Value;
            app.I_m_i(3,2,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: I_33EditField_2
        function I_33EditField_2ValueChanged(app, event)
            value = app.I_33EditField_2.Value;
            app.I_m_i(3,3,str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if(strcmp(value,"Revolute"))
                app.JointType(str2num(app.SelectedJoint)) = {'Revolute'};
            else
                app.JointType(str2num(app.SelectedJoint)) = {'Prismatic'};
            end
        end

        % Button pushed function: GenerateRobotButton
        function GenerateRobotButtonPushed(app, event)
             try
                app.StatusNoRobotProvidedLabel.Text = "Status: Updating...";
                
                %nx1 -- DH(i,:) = [d theta a alpha]
                DH = [app.d_i app.theta_i app.a_i app.alpha_i];
                
                % Robot Options
                OPTIONS.numLinks    = app.NumJoints;
                OPTIONS.workspace   = max(max(app.a_i),max(app.d_i))*3;     % Workspace Size
                OPTIONS.gravity     = app.GravityVector;   % Gravity Vector
    
                % Declare some Link Options
                qlims   = cell(OPTIONS.numLinks, 1);
                I       = cell(OPTIONS.numLinks, 1);
                I_m     = cell(OPTIONS.numLinks, 1);
                COM     = cell(OPTIONS.numLinks, 1);
                TC      = cell(OPTIONS.numLinks, 1);
                JointTypeVector = zeros(1, OPTIONS.numLinks);
    
                for i=1:OPTIONS.numLinks
    
                    %set joint type
                    if(strcmp(app.JointType(i),"Revolute"))
                        JointTypeVector(i) =1;
                    end
    
                    qlims{i} = [app.JointParameterMin_i(i) app.JointParameterMax_i(i)];
                    I{i} = app.I_L_i(:,:,i);
                    I_m{i} =app.I_m_i(:,:,i);   
                    COM{i} = zeros(3, 1);
                    TC{i} = ones(2, 1)*app.CoulombFrictionCoeff_i(i);
    
                end
                
                OPTIONS.isRevolute  = JointTypeVector;          % Link Type (1=revolute, 0=prismatic)
                OPTIONS.offset      = zeros(OPTIONS.numLinks);  % Joint Param. Offset --???
                OPTIONS.qlims       = qlims;                    % Joint Param. Limits
                OPTIONS.inertia     = I;                        % Inertia Matrix
                OPTIONS.mass        = app.MotorMass_i;          % Mass
                OPTIONS.centerOfMass= COM;                      % Center of Mass --???
                OPTIONS.jointFric   = app.ViscousFrictionCoeff_i;  % Joint Friction
                OPTIONS.coulombFric = TC;                       % Coulomb/Static Friction --???
                OPTIONS.gearRatio   = app.kr_i;                 % Gear Ratio
                OPTIONS.motorInertia= I_m;                      % Motor Inertia --???
                
                % END ROBOT SPECIFICATION %
            
                app.ManipulatorSerialLinkObject = make_robot(app, DH, OPTIONS);
                app.StatusNoRobotProvidedLabel.Text = "Status: Robot Provided";
                
                figure(1)
                clf
                app.ManipulatorSerialLinkObject.plot(app.q_0_i.');

                app.CurrentRobotDetailTextArea.Value = cellstr( app.ManipulatorSerialLinkObject.char);

            catch
                 app.StatusNoRobotProvidedLabel.Text = "Status: Error in Provided Robot";
            end

            
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            value = app.AlphaEditField.Value;
            app.alpha_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: ThetaEditField
        function ThetaEditFieldValueChanged(app, event)
            value = app.ThetaEditField.Value;
            app.theta_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.a_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            value = app.dEditField.Value;
            app.d_i(str2num(app.SelectedJoint)) = value;
        end

        % Button pushed function: GenerateAnalyticalEquationsofMotionButton
        function GenerateAnalyticalEquationsofMotionButtonPushed(app, event)
          % try
               app.TextArea.Value = "Loading...";
               %generate g_unitVector
               g_unitVector = (app.GravityVector/norm(app.GravityVector)).';
                
               Symbolic_q =sym('q', [app.NumJoints,1]);
               Symbolic_a =sym('a', [app.NumJoints,1]);
               Symbolic_d =sym('d', [app.NumJoints,1]);
               Symbolic_theta =sym('theta', [app.NumJoints,1]);
    
               JointTypeVector = ones(1, app.NumJoints);
               for i = 1:app.NumJoints
    
                    %set joint type
                    if(strcmp(app.JointType(i),"Revolute"))
                        JointTypeVector(i) = 0;
                        Symbolic_theta(i) = Symbolic_q(i);
                    else
                        Symbolic_d(i) = Symbolic_q(i);
                    end
    
                    if(app.a_i ==0)
                        Symbolic_a(i) = 0;
                    end
    
                      if((app.d_i ==0) & (JointTypeVector(i) == 0 ))
                        Symbolic_d(i) = 0;
                      end
    
                    if((app.theta_i) ==0 & (JointTypeVector(i) == 1))
                        Symbolic_theta(i) = 0;
                    end
               end
    
    
                dh_params = [Symbolic_a, app.alpha_i, Symbolic_d, Symbolic_theta];
    
                app.FullAnalyticalEquation = GenerateAnalyticalEquations(app, app.NumJoints, g_unitVector, JointTypeVector, dh_params);
                
                for i= 1:app.NumJoints
                      EquationString(i)= {strcat('Equation #', num2str(i), ':',32,char( app.FullAnalyticalEquation(i)),10)};
                end
                
                app.TextArea.Value = EquationString;
          % catch
          %       app.TextArea.Value = "Error in trying to generate Analytical Equations. Please check inputs.";
          % end

        end

        % Button pushed function: GenerateJointDyanamicsButton
        function GenerateJointDyanamicsButtonPushed(app, event)
             % try

                syms t
                try
                    %get time step information and initialize trajectory matrix
                    timeSteps = 0: app.TimeStepSizeEditField.Value :app.FinalTimeEditField.Value;
                    TrajectoryTransMatrix = zeros(4,4,length(timeSteps)); %homogeneous transformation matrix
    
                    % initialized function handles for angles so that if we
                    % dont use the angles, the R matrix is just the eye
                    x_func=@(t) 0*t;
                    y_func=@(t) 0*t;
                    z_func=@(t) 0*t;
                    phi_func=@(t) 0*t;
                    theta_func=@(t) 0*t;
                    psi_func=@(t) 0*t;
                catch ME
                    rethrow(ME)
                end
               

                try
                    %find which Dimensions we will be specifying with
                    %trajectory equations--m is a mask vector (1 x 6)
                    % which specifies the Cartesian DOF (in the wrist 
                    % coordinate frame) that will be ignored
                    m = zeros(1,6);
                    if(app.ActivateXCheckBox.Value ==1)
                        m(1) = 1;
                    end
                    if(app.ActivateYCheckBox.Value ==1)
                        m(2) = 1;
                    end
                    if(app.ActivateZCheckBox.Value ==1)
                        m(3) = 1;
                    end
                    if(app.ActivatePhiCheckBox.Value ==1)
                        m(4) = 1;
                    end
                    if(app.ActivateThetaCheckBox.Value ==1)
                        m(5) = 1;
                    end
                    if(app.ActivatePsiCheckBox.Value ==1)
                        m(6) = 1;
                    end
                catch ME
                    rethrow(ME)
                end

                try
                    %convert equation input to syms and then into functions
                    if(m(1) == 1)
                        xeq_sym = str2sym(app.XtEditField.Value);
                        x_func = matlabFunction(xeq_sym,"Vars",t);
                    end
        
                    if(m(2) == 1)
                        yeq_sym = str2sym(app.YtEditField.Value);
                        y_func = matlabFunction(yeq_sym,"Vars",t);
                    end
        
                    if(m(3) == 1)
                        zeq_sym = str2sym(app.ZtEditField.Value);
                        z_func = matlabFunction(zeq_sym,"Vars",t);
                    end
        
                    if(m(5) == 1)
                        thetaeq_sym = str2sym(app.ThetatEditField.Value);
                        theta_func = matlabFunction(thetaeq_sym,"Vars",t);
                    end
        
                    if(m(4) == 1)
                        phieq_sym = str2sym(app.PhitEditField.Value);
                        phi_func = matlabFunction(phieq_sym,"Vars",t);
                    end
        
                    if(m(6) == 1)
                        psieq_sym = str2sym(app.PsitEditField.Value);
                        psi_func = matlabFunction(psieq_sym,"Vars",t);
                    end

                    app.TrajectoryErrorLable.Text =" ";
                catch
                    app.TrajectoryErrorLable.Text ="Error in Trajectory";
                end
    
                %build transformation matrices for each time step
                for i= 1:length(timeSteps)
    
                    R = EulerAnglesRotationMatrix(app, 'zyz', phi_func(timeSteps(i)), theta_func(timeSteps(i)), psi_func(timeSteps(i)));
    
                    TrajectoryTransMatrix(1:3,1:3,i) = R;
                    TrajectoryTransMatrix(1,4,i) = x_func(timeSteps(i));
                    TrajectoryTransMatrix(2,4,i) = y_func(timeSteps(i));
                    TrajectoryTransMatrix(3,4,i) = z_func(timeSteps(i));
                    TrajectoryTransMatrix(4,4,i) = 1;
                end
            
                try
                    %calculate the joint variables using ikine
                    q_Trajectory = app.ManipulatorSerialLinkObject.ikine(TrajectoryTransMatrix, app.q_0_i.', m);
                catch ME
                    disp('error computing inverse kinematics');
                    rethrow(ME)
                end

                try
                    q_Trajectory= unwrap(q_Trajectory);
    
                    %numerically differentiate to get dq and ddq
                    dq_Trajectory = diff(q_Trajectory)./app.TimeStepSizeEditField.Value;
                    ddq_Trajectory = diff(dq_Trajectory)./app.TimeStepSizeEditField.Value;
    
                catch ME
                    rethrow(ME)
                end
                try
                    %lets check how our inverse kinematics did
                    ForwardKinematicCheck = app.ManipulatorSerialLinkObject.fkine((q_Trajectory));

                    x_exact = zeros(1,length(timeSteps));
                    y_exact = zeros(1,length(timeSteps));
                    z_exact = zeros(1,length(timeSteps));
                    for i =1:length(timeSteps)
                        x_exact(i) = ForwardKinematicCheck(1,4,i);
                        y_exact(i) = ForwardKinematicCheck(2,4,i);
                        z_exact(i) = ForwardKinematicCheck(3,4,i);
                    end

                
                catch ME
                    rethrow(ME)
                end
                try
                    %plot it all up
                    legendText={};
                    for i = 1:app.NumJoints
                        legendText{i} = ['Joint #',num2str(i)];
                    end
    
                    x_plot = x_func(timeSteps(:));
                    if(size(x_plot) == 1)
                        x_plot = ones(length(timeSteps))*x_plot;
                    end
                    y_plot = y_func(timeSteps(:));
                    if(size(y_plot) == 1)
                        y_plot = ones(length(timeSteps))*y_plot;
                    end
                    z_plot = z_func(timeSteps(:));
                    if(size(z_plot) == 1)
                        z_plot = ones(lenght(timeSteps))*z_plot;
                    end
                    
                    plot3(app.UIAxes2, x_plot, y_plot, z_plot, x_exact,y_exact,z_exact )
                    
                    legend(app.UIAxes2, {'Desired Path', 'Actual Path'}, 'Location','best')
                catch ME
                    rethrow(ME)
                end

                try
                    plot(app.UIAxes,timeSteps,q_Trajectory);
                    legend(app.UIAxes, legendText, 'Location','best')
                    plot(app.UIAxes_2,timeSteps(1:length(dq_Trajectory)),dq_Trajectory);
                    legend(app.UIAxes_2, legendText, 'Location','best')
                    plot(app.UIAxes_3,timeSteps(1:length(ddq_Trajectory)),ddq_Trajectory);
                    legend(app.UIAxes_3, legendText, 'Location','best')
                catch ME
                    rethrow(ME)
                end
                app.DynamicsGeneratorErrorLable.Text = "";

                % app.ManipulatorSerialLinkObject.animate((q_Trajectory))
            %  catch ME
            %      rethrow(ME)
            %     app.DynamicsGeneratorErrorLable.Text = "Error: Please Verify Robot and Trajectory are Correct";
            % end

        end

        % Value changed function: JointMaxEditField
        function JointMaxEditFieldValueChanged(app, event)
            value = app.JointMaxEditField.Value;
            app.JointParameterMax_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: JointMinEditField
        function JointMinEditFieldValueChanged(app, event)
            value = app.JointMinEditField.Value;
            app.JointParameterMin_i(str2num(app.SelectedJoint)) = value;
        end

        % Value changed function: InitialJointPositionEditField
        function InitialJointPositionEditFieldValueChanged(app, event)
            value = app.InitialJointPositionEditField.Value;
            app.q_0_i(str2num(app.SelectedJoint)) = value;
        end

        % Button pushed function: RunComplainceControlButton
        function RunComplainceControlButtonPushed(app, event)
            app.Label_2.Text = "Loading...";
            app.numActiveDofs = 0;
            app.activeDOF = zeros(6,1);

            app.StopTime = app.StopTimeEditField.Value;
            
            ForcesymEqs = {};
            XdsymEqs = {};

            LegendLables = {};
            LegendLables_Desired = {};
            syms t;
                
            if(app.ActivateXCheckBox_2.Value ==1)
                app.activeDOF(1) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FxtEditField.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.XtEditField_3.Value));
                LegendLables(end+1)= {"X_e"};
                LegendLables_Desired(end+1)= {"X_d"};
            end
            if(app.ActivateYCheckBox_2.Value ==1)
                app.activeDOF(2) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FytEditField.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.YtEditField_3.Value));
                LegendLables(end+1)= {"Y_e"};
                LegendLables_Desired(end+1)= {"Y_d"};
            end
            if(app.ActivateZCheckBox_2.Value == 1)
                app.activeDOF(3) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FztEditField.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.XtEditField_3.Value));
                LegendLables(end+1)= {"Z_e"};
                LegendLables_Desired(end+1)= {"Z_d"};
            end
            if(app.ActivatePhiCheckBox_2.Value ==1)
                app.activeDOF(4) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FPhitEditField_2.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.PhitEditField_3.Value));
                LegendLables(end+1)= {"\phi_e"};
                LegendLables_Desired(end+1)= {"\phi_d"};
            end
            if(app.ActivateThetaCheckBox_2.Value ==1)
                app.activeDOF(5) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FThetatEditField.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.ThetatEditField_3.Value));
                LegendLables(end+1)= {"\theta_e"};
                LegendLables_Desired(end+1)= {"\theta_d"};
            end
            if(app.ActivatePsiCheckBox_2.Value ==1)
                app.activeDOF(6) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FPsitEditField.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.PsitEditField_3.Value));
                LegendLables(end+1)= {"\psi_e"};
                LegendLables_Desired(end+1)= {"\psi_d"};
            end


            app.Kp = diag([app.KpxEditField.Value, app.KpyEditField.Value, app.KpzEditField.Value,app.KpPhiEditField_2.Value,app.KpThetaEditField_2.Value,app.KpPsiEditField_2.Value]);
            app.Kd = diag([app.KdxEditField.Value, app.KdyEditField.Value, app.KdzEditField.Value,app.KdPhiEditField_2.Value,app.KdThetaEditField_2.Value,app.KdPsiEditField_2.Value]);
        
            for i = 6:-1:1
                if app.activeDOF(i) == 0
                   app.Kp(i,:) =[];
                   app.Kp(:,i) =[];

                   app.Kd(i,:) =[];
                   app.Kd(:,i) =[];

                else
                   app.numActiveDofs = app.numActiveDofs +1;
                end
            end

                        
            app.Calc_He = matlabFunction([ForcesymEqs(:)]',"Vars",t);
            app.Calc_Xd = matlabFunction([XdsymEqs(:)],"Vars",t);
           
            GenerateDynamicFunctions(app)
            
            %allow simulink to access app funcs and vars
            assignin("base","app",app)

            %run simulink for compliance control
            ComplianceControlData = sim('ComplianceControl.slx');
            time = ComplianceControlData.SimTime.Data;
            Xe = squeeze(ComplianceControlData.Xe.Data);
            q_sim = squeeze(ComplianceControlData.q_sim.Data);
            
            ForceInputs= zeros(length(time),app.numActiveDofs);
            XdInputs= zeros(length(time),app.numActiveDofs);
            for i =1:length(time)
                ForceInputs(i,:)= app.Calc_He(time(i));
                XdInputs(i,:)= app.Calc_Xd(time(i));
            end


            legendText={};
            for i = 1:app.NumJoints
                legendText{i} = ['Joint #',num2str(i)];
            end
            
            plot(app.UIAxes3_4,time, q_sim)
            legend(app.UIAxes3_4, legendText, 'Location','best')

            plot(app.UIAxes3,time,Xe, '-', time, XdInputs,'--')
            legend(app.UIAxes3,[LegendLables, LegendLables_Desired],'Location','best')

            plot(app.UIAxes4,time, ForceInputs)
            legend(app.UIAxes4,LegendLables,'Location','best')

            app.Label_2.Text = "";

            app.ManipulatorSerialLinkObject.animate((q_sim)')
        end

        % Button pushed function: RunImpedanceControlButton
        function RunImpedanceControlButtonPushed(app, event)
           app.Label.Text = "Loading...";

            %lets figure out which joints are active
            app.activeDOF= zeros(6,1);
            app.numActiveDofs = 0;

            ForcesymEqs = {};
            XdsymEqs = {};

            LegendLables = {};
            LegendLables_Desired = {};

            if(app.ActivateXCheckBox_2.Value ==1)
                app.activeDOF(1) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FxtEditField_2.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.XtEditField_2.Value));
                LegendLables(end+1)= {"X_e"};
                LegendLables_Desired(end+1)= {"X_d"};
            end
            if(app.ActivateYCheckBox_2.Value ==1)
                app.activeDOF(2) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FytEditField_2.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.YtEditField_2.Value));
                LegendLables(end+1)= {"Y_e"};
                LegendLables_Desired(end+1)= {"Y_d"};
            end
            if(app.ActivateZCheckBox_2.Value == 1)
                app.activeDOF(3) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FztEditField_2.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.XtEditField_2.Value));
                LegendLables(end+1)= {"Z_e"};
                LegendLables_Desired(end+1)= {"Z_d"};
            end
            if(app.ActivatePhiCheckBox_2.Value ==1)
                app.activeDOF(4) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FPhitEditField_2.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.PhitEditField_2.Value));
                LegendLables(end+1)= {"\phi_e"};
                LegendLables_Desired(end+1)= {"\phi_d"};
            end
            if(app.ActivateThetaCheckBox_2.Value ==1)
                app.activeDOF(5) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FThetatEditField_2.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.ThetatEditField_2.Value));
                LegendLables(end+1)= {"\theta_e"};
                LegendLables_Desired(end+1)= {"\theta_d"};
            end
            if(app.ActivatePsiCheckBox_2.Value ==1)
                app.activeDOF(6) = 1;
                ForcesymEqs(end+1) = sym2cell(str2sym(app.FPsitEditField_2.Value)) ;
                XdsymEqs(end+1) = sym2cell(str2sym(app.PsitEditField_2.Value));
                LegendLables(end+1)= {"\psi_e"};
                LegendLables_Desired(end+1)= {"\psi_d"};
            end

            %pull sim settings from GUI
            app.StopTime = app.StopTimeEditField_2.Value;
            app.Kp = diag([app.KpxEditField_2.Value, app.KpyEditField_2.Value, app.KpzEditField_2.Value,app.KpPhiEditField.Value,app.KpThetaEditField.Value,app.KpPsiEditField.Value]);
            app.Kd = diag([app.KdxEditField_2.Value, app.KdyEditField_2.Value, app.KdzEditField_2.Value,app.KdPhiEditField.Value,app.KdThetaEditField.Value,app.KdPsiEditField.Value]);
            app.Md = diag([app.MdxEditField.Value, app.MdyEditField.Value, app.MdzEditField.Value,app.MdPhiEditField.Value,app.MdThetaEditField.Value,app.MdPsiEditField.Value]);

            for i = 6:-1:1
                if app.activeDOF(i) == 0
                   app.Kp(i,:) =[];
                   app.Kp(:,i) =[];

                   app.Kd(i,:) =[];
                   app.Kd(:,i) =[];

                   app.Md(i,:) =[];
                   app.Md(:,i) =[];

                else
                   app.numActiveDofs = app.numActiveDofs +1;
                end
            end

            syms t;

            %find He as a func of time
            app.Calc_He = matlabFunction([ForcesymEqs(:)]',"Vars",t);
 
            app.Calc_Xd = matlabFunction([XdsymEqs(:)],"Vars",t);

            %generate functions
            GenerateDynamicFunctions(app)

            %allow simulink to access app funcs and vars
            assignin("base","app",app)

            %run simulink for compliance control            
            ImpedenceControlData = sim('ImpedenceControl.slx');
            time = ImpedenceControlData.SimTime.Data;
            Xe = squeeze(ImpedenceControlData.Xe.Data);
            q_sim = squeeze(ImpedenceControlData.q_sim.Data);
            
            ForceInputs= zeros(length(time),app.numActiveDofs);
            XdInputs= zeros(length(time),app.numActiveDofs);
            for i =1:length(time)
                ForceInputs(i,:)= app.Calc_He(time(i));
                XdInputs(i,:)= app.Calc_Xd(time(i));
            end

            legendText={};
            for i = 1:app.NumJoints
                legendText{i} = ['Joint #',num2str(i)];
            end

            plot(app.UIAxes3_3,time, q_sim)
            legend(app.UIAxes3_3, legendText , 'Location','best')

            plot(app.UIAxes4_2,time, ForceInputs)
            legend(app.UIAxes4_2, LegendLables, 'Location','best')

            plot(app.UIAxes3_2,time,Xe,'-',time,XdInputs, '--')
            legend(app.UIAxes3_2, [LegendLables, LegendLables_Desired], 'Location','best')
            app.Label.Text = "";
            
            app.ManipulatorSerialLinkObject.animate((q_sim)')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeometryInputsTab = uitab(app.TabGroup);
            app.GeometryInputsTab.Title = 'Geometry Inputs';
            app.GeometryInputsLabel = uilabel(app.GeometryInputsTab);
            app.GeometryInputsLabel.Text = 'Geometry Inputs';
            app.GravityComponentsLabel = uilabel(app.GeometryInputsTab);
            app.GravityComponentsLabel.Text = 'Gravity Components';
            app.XLabel = uilabel(app.GeometryInputsTab);
            app.XLabel.Text = 'X:';
            app.XEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YLabel = uilabel(app.GeometryInputsTab);
            app.YLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZLabel = uilabel(app.GeometryInputsTab);
            app.ZLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.GeometryInputsTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Value = -9.81;
            app.NumberofJointsSpinnerLabel = uilabel(app.GeometryInputsTab);
            app.NumberofJointsSpinnerLabel.Text = 'Number of Joints ';
            app.NumberofJointsSpinner = uispinner(app.GeometryInputsTab);
            app.NumberofJointsSpinner.Limits = [1 Inf];
            app.NumberofJointsSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofJointsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofJointsSpinnerValueChanged, true);
            app.NumberofJointsSpinner.Value = 1;
            app.SelectedJointLabel = uilabel(app.GeometryInputsTab);
            app.SelectedJointLabel.Text = 'Selected Joint: ';
            app.SelectedJointDropDown = uidropdown(app.GeometryInputsTab);
            app.SelectedJointDropDown.Items = {};
            app.SelectedJointDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedJointDropDownValueChanged, true);
            app.SelectedJointDropDown.Value = {};
            app.PleaseSelectJointPanel = uipanel(app.GeometryInputsTab);
            app.PleaseSelectJointPanel.Title = 'Please Select Joint';
            app.DHParametersPanel = uipanel(app.PleaseSelectJointPanel);
            app.DHParametersPanel.Title = 'DH Parameters';
            app.AlphaEditFieldLabel = uilabel(app.DHParametersPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.ThetaEditFieldLabel = uilabel(app.DHParametersPanel);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
            app.aEditFieldLabel = uilabel(app.DHParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.dEditFieldLabel = uilabel(app.DHParametersPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.DHParametersPanel, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.Switch_2 = uiswitch(app.DHParametersPanel, 'slider');
            app.Switch_2.Items = {'Revolute', 'Prismatic'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Revolute';
            app.MassPropertiesPanel = uipanel(app.PleaseSelectJointPanel);
            app.MassPropertiesPanel.Title = 'Mass Properties';
            app.LinkPropertiesPanel = uipanel(app.MassPropertiesPanel);
            app.LinkPropertiesPanel.Title = 'Link Properties';
            app.MassEditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.MassEditField.Value = 1;
            app.I_11EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_11EditFieldLabel.Text = 'I_11';
            app.I_11EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_11EditField.ValueChangedFcn = createCallbackFcn(app, @I_11EditFieldValueChanged, true);
            app.I_11EditField.Value = 1;
            app.I_12EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_12EditFieldLabel.Text = 'I_12';
            app.I_12EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_12EditField.ValueChangedFcn = createCallbackFcn(app, @I_12EditFieldValueChanged, true);
            app.I_13EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_13EditFieldLabel.Text = 'I_13';
            app.I_13EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_13EditField.ValueChangedFcn = createCallbackFcn(app, @I_13EditFieldValueChanged, true);
            app.I_21EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_21EditFieldLabel.Text = 'I_21';
            app.I_21EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_21EditField.ValueChangedFcn = createCallbackFcn(app, @I_21EditFieldValueChanged, true);
            app.I_22EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_22EditFieldLabel.Text = 'I_22';
            app.I_22EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_22EditField.ValueChangedFcn = createCallbackFcn(app, @I_22EditFieldValueChanged, true);
            app.I_22EditField.Value = 1;
            app.I_23EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_23EditFieldLabel.Text = 'I_23';
            app.I_23EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_23EditField.ValueChangedFcn = createCallbackFcn(app, @I_23EditFieldValueChanged, true);
            app.I_31EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_31EditFieldLabel.Text = 'I_31';
            app.I_31EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_31EditField.ValueChangedFcn = createCallbackFcn(app, @I_31EditFieldValueChanged, true);
            app.I_32EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_32EditFieldLabel.Text = 'I_32';
            app.I_32EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_32EditField.ValueChangedFcn = createCallbackFcn(app, @I_32EditFieldValueChanged, true);
            app.I_33EditFieldLabel = uilabel(app.LinkPropertiesPanel);
            app.I_33EditFieldLabel.Text = 'I_33';
            app.I_33EditField = uieditfield(app.LinkPropertiesPanel, 'numeric');
            app.I_33EditField.ValueChangedFcn = createCallbackFcn(app, @I_33EditFieldValueChanged, true);
            app.I_33EditField.Value = 1;
            app.MotorPropertiesPanel = uipanel(app.MassPropertiesPanel);
            app.MotorPropertiesPanel.Title = 'Motor Properties';
            app.MassEditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.MassEditField_2Label.Text = 'Mass';
            app.MassEditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.MassEditField_2.ValueChangedFcn = createCallbackFcn(app, @MassEditField_2ValueChanged, true);
            app.MassEditField_2.Value = 1;
            app.I_11EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_11EditField_2Label.Text = 'I_11';
            app.I_11EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_11EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_11EditField_2ValueChanged, true);
            app.I_11EditField_2.Value = 1;
            app.I_12EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_12EditField_2Label.Text = 'I_12';
            app.I_12EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_12EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_12EditField_2ValueChanged, true);
            app.I_13EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_13EditField_2Label.Text = 'I_13';
            app.I_13EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_13EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_13EditField_2ValueChanged, true);
            app.I_21EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_21EditField_2Label.Text = 'I_21';
            app.I_21EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_21EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_21EditField_2ValueChanged, true);
            app.I_22EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_22EditField_2Label.Text = 'I_22';
            app.I_22EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_22EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_22EditField_2ValueChanged, true);
            app.I_22EditField_2.Value = 1;
            app.I_23EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_23EditField_2Label.Text = 'I_23';
            app.I_23EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_23EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_23EditField_2ValueChanged, true);
            app.I_31EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_31EditField_2Label.Text = 'I_31';
            app.I_31EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_31EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_31EditField_2ValueChanged, true);
            app.I_32EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_32EditField_2Label.Text = 'I_32';
            app.I_32EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_32EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_32EditField_2ValueChanged, true);
            app.I_33EditField_2Label = uilabel(app.MotorPropertiesPanel);
            app.I_33EditField_2Label.Text = 'I_33';
            app.I_33EditField_2 = uieditfield(app.MotorPropertiesPanel, 'numeric');
            app.I_33EditField_2.ValueChangedFcn = createCallbackFcn(app, @I_33EditField_2ValueChanged, true);
            app.I_33EditField_2.Value = 1;
            app.OtherParametersPanel = uipanel(app.PleaseSelectJointPanel);
            app.OtherParametersPanel.Title = 'Other Parameters';
            app.Kr_iEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.Kr_iEditFieldLabel.Text = 'Kr_i';
            app.Kr_iEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.Kr_iEditField.ValueChangedFcn = createCallbackFcn(app, @Kr_iEditFieldValueChanged, true);
            app.Kr_iEditField.Value = 1;
            app.ViscousFrictionCoefEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.ViscousFrictionCoefEditFieldLabel.Text = 'Viscous Friction Coef';
            app.ViscousFrictionCoefEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.ViscousFrictionCoefEditField.ValueChangedFcn = createCallbackFcn(app, @ViscousFrictionCoefEditFieldValueChanged, true);
            app.CoulombFrictionCoeffLabel = uilabel(app.OtherParametersPanel);
            app.CoulombFrictionCoeffLabel.Text = 'Coulomb Friction Coeff.';
            app.CoulombFrictionCoeffEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.CoulombFrictionCoeffEditField.ValueChangedFcn = createCallbackFcn(app, @CoulombFrictionCoeffEditFieldValueChanged, true);
            app.JointMaxEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.JointMaxEditFieldLabel.Text = 'Joint Max';
            app.JointMaxEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.JointMaxEditField.ValueChangedFcn = createCallbackFcn(app, @JointMaxEditFieldValueChanged, true);
            app.JointMaxEditField.Value = 1000;
            app.JointMinEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.JointMinEditFieldLabel.Text = 'Joint Min';
            app.JointMinEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.JointMinEditField.ValueChangedFcn = createCallbackFcn(app, @JointMinEditFieldValueChanged, true);
            app.JointMinEditField.Value = -1000;
            app.InitialJointPositionEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.InitialJointPositionEditFieldLabel.Text = 'Initial Joint Position';
            app.InitialJointPositionEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.InitialJointPositionEditField.ValueChangedFcn = createCallbackFcn(app, @InitialJointPositionEditFieldValueChanged, true);
            app.InitialJointPositionEditField.Value = 1;
            app.GenerateRobotButton = uibutton(app.GeometryInputsTab, 'push');
            app.GenerateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRobotButtonPushed, true);
            app.GenerateRobotButton.Text = 'Generate Robot';
            app.StatusNoRobotProvidedLabel = uilabel(app.GeometryInputsTab);
            app.StatusNoRobotProvidedLabel.Text = 'Status: No Robot Provided';
            app.WarningchangingvaluewillclearalljointdataLabel = uilabel(app.GeometryInputsTab);
            app.WarningchangingvaluewillclearalljointdataLabel.Text = 'Warning: changing value will clear all joint data';
            app.NoteAvoidInitialRobotPositionatSingularityLabel = uilabel(app.GeometryInputsTab);
            app.NoteAvoidInitialRobotPositionatSingularityLabel.Text = 'Note: Avoid Initial Robot Position at Singularity';
            app.TextArea_2 = uitextarea(app.GeometryInputsTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Notes:'; '- Avoid initial Robot Position at singularity'; ''; ''};
            app.CurrentRobotDetailTextAreaLabel = uilabel(app.GeometryInputsTab);
            app.CurrentRobotDetailTextAreaLabel.Text = 'Current Robot Detail';
            app.CurrentRobotDetailTextArea = uitextarea(app.GeometryInputsTab);
            app.CurrentRobotDetailTextArea.Value = {'Robot Parameters will appear here after clicking "Generate Robot"'};
            app.EoMsTab = uitab(app.TabGroup);
            app.EoMsTab.Title = 'EoMs';
            app.TextArea = uitextarea(app.EoMsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Please enter a valid robot arm in "Geometry Inputs".'; 'Then when you are ready click "Generate Analytical Equations of Motion". This will generate the 2nd order ODEs for the robotic arm in symbolic terms!'};
            app.GenerateAnalyticalEquationsofMotionButton = uibutton(app.EoMsTab, 'push');
            app.GenerateAnalyticalEquationsofMotionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAnalyticalEquationsofMotionButtonPushed, true);
            app.GenerateAnalyticalEquationsofMotionButton.Text = 'Generate Analytical Equations of Motion';
            app.JointDynamicsTab = uitab(app.TabGroup);
            app.JointDynamicsTab.Title = 'Joint Dynamics ';
            app.UIAxes = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes, 'q(t)')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'q')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes_2, 'q_{dot}(t)')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'q_{dot}')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes_3, 'q_{ddot}(t)')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'q_{ddot}')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2 = uiaxes(app.JointDynamicsTab);
            title(app.UIAxes2, 'Desired Path')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.JointDynamicsLabel = uilabel(app.JointDynamicsTab);
            app.JointDynamicsLabel.Text = 'Joint Dynamics ';
            app.TrajectoriesasafunctionoftimePanel = uipanel(app.JointDynamicsTab);
            app.TrajectoriesasafunctionoftimePanel.Title = 'Trajectories as a function of time: ';
            app.XtEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.XtEditFieldLabel.Text = 'X(t)';
            app.XtEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.XtEditField.Value = 'cos(3.14*t)';
            app.YtEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.YtEditFieldLabel.Text = 'Y(t)';
            app.YtEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.YtEditField.Value = 'sin(3.14*t)';
            app.ZtEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.ZtEditFieldLabel.Text = 'Z(t)';
            app.ZtEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.ZtEditField.Value = 'E.g.: sin(3.14*t)';
            app.ThetatEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.ThetatEditFieldLabel.Text = 'Theta(t)';
            app.ThetatEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.ThetatEditField.Value = 'Please enter an equation';
            app.PhitEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.PhitEditFieldLabel.Text = 'Phi(t)';
            app.PhitEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.PhitEditField.Value = 'Please enter an equation';
            app.PsitEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.PsitEditFieldLabel.Text = 'Psi(t)';
            app.PsitEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'text');
            app.PsitEditField.Value = 'Please enter an equation';
            app.FinalTimeEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'numeric');
            app.FinalTimeEditField.Value = 1;
            app.TimeStepSizeEditFieldLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.TimeStepSizeEditFieldLabel.Text = 'Time Step Size';
            app.TimeStepSizeEditField = uieditfield(app.TrajectoriesasafunctionoftimePanel, 'numeric');
            app.TimeStepSizeEditField.Value = 0.01;
            app.ActivateXCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateXCheckBox.Text = 'Activate X';
            app.ActivateYCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateYCheckBox.Text = 'Activate Y';
            app.ActivateZCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateZCheckBox.Text = 'Activate Z';
            app.ActivateThetaCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivateThetaCheckBox.Text = 'Activate Theta';
            app.ActivatePhiCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivatePhiCheckBox.Text = 'Activate Phi';
            app.ActivatePsiCheckBox = uicheckbox(app.TrajectoriesasafunctionoftimePanel);
            app.ActivatePsiCheckBox.Text = 'Activate Psi';
            app.NotePleaseprovideposeintheZYZschemeLabel = uilabel(app.TrajectoriesasafunctionoftimePanel);
            app.NotePleaseprovideposeintheZYZschemeLabel.Text = 'Note: Please provide pose in the ZYZ scheme';
            app.GenerateJointDyanamicsButton = uibutton(app.JointDynamicsTab, 'push');
            app.GenerateJointDyanamicsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateJointDyanamicsButtonPushed, true);
            app.GenerateJointDyanamicsButton.Text = 'Generate Joint Dyanamics';
            app.DynamicsGeneratorErrorLable = uilabel(app.JointDynamicsTab);
            app.DynamicsGeneratorErrorLable.Text = '';
            app.TrajectoryErrorLable = uilabel(app.JointDynamicsTab);
            app.TrajectoryErrorLable.Text = '';
            app.ComplianceControlTab = uitab(app.TabGroup);
            app.ComplianceControlTab.Title = 'Compliance Control';
            app.UIAxes3 = uiaxes(app.ComplianceControlTab);
            title(app.UIAxes3, 'Xe vs Xd')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Position')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.ComplianceControlTab);
            title(app.UIAxes4, 'End Effector Force')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Force')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes3_4 = uiaxes(app.ComplianceControlTab);
            title(app.UIAxes3_4, 'Joint Variables')
            xlabel(app.UIAxes3_4, 'Time')
            ylabel(app.UIAxes3_4, 'q(t)')
            zlabel(app.UIAxes3_4, 'Z')
            app.RunComplainceControlButton = uibutton(app.ComplianceControlTab, 'push');
            app.RunComplainceControlButton.ButtonPushedFcn = createCallbackFcn(app, @RunComplainceControlButtonPushed, true);
            app.RunComplainceControlButton.Text = 'Run Complaince Control';
            app.EndEffectorForceInputsLabel_2 = uilabel(app.ComplianceControlTab);
            app.EndEffectorForceInputsLabel_2.Text = 'End Effector Force Inputs';
            app.endeffectorframeinRPYframeLabel = uilabel(app.ComplianceControlTab);
            app.endeffectorframeinRPYframeLabel.Text = '(end effector frame in RPY frame)';
            app.ComplianceControlLabel = uilabel(app.ComplianceControlTab);
            app.ComplianceControlLabel.Text = 'Compliance Control';
            app.FxtEditFieldLabel = uilabel(app.ComplianceControlTab);
            app.FxtEditFieldLabel.Text = 'Fx(t)';
            app.FxtEditField = uieditfield(app.ComplianceControlTab, 'text');
            app.FxtEditField.Value = 'cos(3.14*t)';
            app.FytEditFieldLabel = uilabel(app.ComplianceControlTab);
            app.FytEditFieldLabel.Text = 'Fy(t)';
            app.FytEditField = uieditfield(app.ComplianceControlTab, 'text');
            app.FytEditField.Value = 'sin(3.14*t)';
            app.FztEditFieldLabel = uilabel(app.ComplianceControlTab);
            app.FztEditFieldLabel.Text = 'Fz(t)';
            app.FztEditField = uieditfield(app.ComplianceControlTab, 'text');
            app.FztEditField.Value = '0';
            app.FPhitEditFieldLabel = uilabel(app.ComplianceControlTab);
            app.FPhitEditFieldLabel.Text = 'FPhi(t)';
            app.FPhitEditField = uieditfield(app.ComplianceControlTab, 'text');
            app.FPhitEditField.Value = '0';
            app.FThetatEditFieldLabel = uilabel(app.ComplianceControlTab);
            app.FThetatEditFieldLabel.Text = 'FTheta(t)';
            app.FThetatEditField = uieditfield(app.ComplianceControlTab, 'text');
            app.FThetatEditField.Value = '0';
            app.FPsitEditFieldLabel = uilabel(app.ComplianceControlTab);
            app.FPsitEditFieldLabel.Text = 'FPsi(t)';
            app.FPsitEditField = uieditfield(app.ComplianceControlTab, 'text');
            app.FPsitEditField.Value = '0';
            app.DesiredMotionPanel = uipanel(app.ComplianceControlTab);
            app.DesiredMotionPanel.Title = 'Desired Motion';
            app.StopTimeEditFieldLabel = uilabel(app.DesiredMotionPanel);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.DesiredMotionPanel, 'numeric');
            app.StopTimeEditField.Value = 10;
            app.ActivateXCheckBox_3 = uicheckbox(app.DesiredMotionPanel);
            app.ActivateXCheckBox_3.Text = 'Activate X';
            app.ActivateXCheckBox_3.Value = true;
            app.ActivateYCheckBox_3 = uicheckbox(app.DesiredMotionPanel);
            app.ActivateYCheckBox_3.Text = 'Activate Y';
            app.ActivateYCheckBox_3.Value = true;
            app.ActivateZCheckBox_3 = uicheckbox(app.DesiredMotionPanel);
            app.ActivateZCheckBox_3.Text = 'Activate Z';
            app.ActivateThetaCheckBox_3 = uicheckbox(app.DesiredMotionPanel);
            app.ActivateThetaCheckBox_3.Text = 'Activate Theta';
            app.ActivatePhiCheckBox_3 = uicheckbox(app.DesiredMotionPanel);
            app.ActivatePhiCheckBox_3.Text = 'Activate Phi';
            app.ActivatePsiCheckBox_3 = uicheckbox(app.DesiredMotionPanel);
            app.ActivatePsiCheckBox_3.Text = 'Activate Psi';
            app.XtEditField_3Label = uilabel(app.DesiredMotionPanel);
            app.XtEditField_3Label.Text = 'X(t)';
            app.XtEditField_3 = uieditfield(app.DesiredMotionPanel, 'text');
            app.XtEditField_3.Value = '0.5*cos(t)';
            app.YtEditField_3Label = uilabel(app.DesiredMotionPanel);
            app.YtEditField_3Label.Text = 'Y(t)';
            app.YtEditField_3 = uieditfield(app.DesiredMotionPanel, 'text');
            app.YtEditField_3.Value = '0.5*sin(t)';
            app.ZtEditField_3Label = uilabel(app.DesiredMotionPanel);
            app.ZtEditField_3Label.Text = 'Z(t)';
            app.ZtEditField_3 = uieditfield(app.DesiredMotionPanel, 'text');
            app.ZtEditField_3.Value = '0';
            app.ThetatEditField_3Label = uilabel(app.DesiredMotionPanel);
            app.ThetatEditField_3Label.Text = 'Theta(t)';
            app.ThetatEditField_3 = uieditfield(app.DesiredMotionPanel, 'text');
            app.ThetatEditField_3.Value = '0';
            app.PhitEditField_3Label = uilabel(app.DesiredMotionPanel);
            app.PhitEditField_3Label.Text = 'Phi(t)';
            app.PhitEditField_3 = uieditfield(app.DesiredMotionPanel, 'text');
            app.PhitEditField_3.Value = '0';
            app.PsitEditField_3Label = uilabel(app.DesiredMotionPanel);
            app.PsitEditField_3Label.Text = 'Psi(t)';
            app.PsitEditField_3 = uieditfield(app.DesiredMotionPanel, 'text');
            app.PsitEditField_3.Value = '0';
            app.endeffectorframeinRPYframeLabel_2 = uilabel(app.DesiredMotionPanel);
            app.endeffectorframeinRPYframeLabel_2.Text = '(end effector frame in RPY frame)';
            app.GainsPanel_2 = uipanel(app.ComplianceControlTab);
            app.GainsPanel_2.Title = 'Gains';
            app.KpxEditFieldLabel = uilabel(app.GainsPanel_2);
            app.KpxEditFieldLabel.Text = 'Kp x';
            app.KpxEditField = uieditfield(app.GainsPanel_2, 'numeric');
            app.KpxEditField.Value = 20;
            app.KpyEditFieldLabel = uilabel(app.GainsPanel_2);
            app.KpyEditFieldLabel.Text = 'Kp y';
            app.KpyEditField = uieditfield(app.GainsPanel_2, 'numeric');
            app.KpyEditField.Value = 20;
            app.KpzEditFieldLabel = uilabel(app.GainsPanel_2);
            app.KpzEditFieldLabel.Text = 'Kp z';
            app.KpzEditField = uieditfield(app.GainsPanel_2, 'numeric');
            app.KpzEditField.Value = 1;
            app.KpPhiEditField_2Label = uilabel(app.GainsPanel_2);
            app.KpPhiEditField_2Label.Text = 'Kp  Phi';
            app.KpPhiEditField_2 = uieditfield(app.GainsPanel_2, 'numeric');
            app.KpPhiEditField_2.Value = 1;
            app.KpThetaEditField_2Label = uilabel(app.GainsPanel_2);
            app.KpThetaEditField_2Label.Text = 'Kp Theta';
            app.KpThetaEditField_2 = uieditfield(app.GainsPanel_2, 'numeric');
            app.KpThetaEditField_2.Value = 1;
            app.KpPsiEditField_2Label = uilabel(app.GainsPanel_2);
            app.KpPsiEditField_2Label.Text = 'Kp Psi';
            app.KpPsiEditField_2 = uieditfield(app.GainsPanel_2, 'numeric');
            app.KpPsiEditField_2.Value = 1;
            app.KdxEditFieldLabel = uilabel(app.GainsPanel_2);
            app.KdxEditFieldLabel.Text = 'Kd x';
            app.KdxEditField = uieditfield(app.GainsPanel_2, 'numeric');
            app.KdxEditField.Value = 5;
            app.KdyEditFieldLabel = uilabel(app.GainsPanel_2);
            app.KdyEditFieldLabel.Text = 'Kd y';
            app.KdyEditField = uieditfield(app.GainsPanel_2, 'numeric');
            app.KdyEditField.Value = 5;
            app.KdzEditFieldLabel = uilabel(app.GainsPanel_2);
            app.KdzEditFieldLabel.Text = 'Kd z';
            app.KdzEditField = uieditfield(app.GainsPanel_2, 'numeric');
            app.KdzEditField.Value = 1;
            app.KdPhiEditField_2Label = uilabel(app.GainsPanel_2);
            app.KdPhiEditField_2Label.Text = 'Kd Phi';
            app.KdPhiEditField_2 = uieditfield(app.GainsPanel_2, 'numeric');
            app.KdPhiEditField_2.Value = 1;
            app.KdThetaEditField_2Label = uilabel(app.GainsPanel_2);
            app.KdThetaEditField_2Label.Text = 'Kd  Theta';
            app.KdThetaEditField_2 = uieditfield(app.GainsPanel_2, 'numeric');
            app.KdThetaEditField_2.Value = 1;
            app.KdPsiEditField_2Label = uilabel(app.GainsPanel_2);
            app.KdPsiEditField_2Label.Text = 'Kd Psi';
            app.KdPsiEditField_2 = uieditfield(app.GainsPanel_2, 'numeric');
            app.KdPsiEditField_2.Value = 1;
            app.Label_2 = uilabel(app.ComplianceControlTab);
            app.Label_2.Text = '';
            app.ImpedanceControlTab = uitab(app.TabGroup);
            app.ImpedanceControlTab.Title = 'Impedance Control';
            app.UIAxes3_2 = uiaxes(app.ImpedanceControlTab);
            title(app.UIAxes3_2, 'Xe vs Xd')
            xlabel(app.UIAxes3_2, 'Time')
            ylabel(app.UIAxes3_2, 'Position')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes4_2 = uiaxes(app.ImpedanceControlTab);
            title(app.UIAxes4_2, 'End Effector Forces')
            xlabel(app.UIAxes4_2, 'Time')
            ylabel(app.UIAxes4_2, 'Force')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.ImpedanceControlTab);
            title(app.UIAxes3_3, 'Joint Variables')
            xlabel(app.UIAxes3_3, 'Time')
            ylabel(app.UIAxes3_3, 'q')
            zlabel(app.UIAxes3_3, 'Z')
            app.RunImpedanceControlButton = uibutton(app.ImpedanceControlTab, 'push');
            app.RunImpedanceControlButton.ButtonPushedFcn = createCallbackFcn(app, @RunImpedanceControlButtonPushed, true);
            app.RunImpedanceControlButton.Text = 'Run Impedance Control';
            app.EndEffectorForceInputsLabel = uilabel(app.ImpedanceControlTab);
            app.EndEffectorForceInputsLabel.Text = 'End Effector Force Inputs';
            app.endeffectorframeinRPYframeLabel_3 = uilabel(app.ImpedanceControlTab);
            app.endeffectorframeinRPYframeLabel_3.Text = '(end effector frame in RPY frame)';
            app.ImpedanceControlLabel = uilabel(app.ImpedanceControlTab);
            app.ImpedanceControlLabel.Text = 'Impedance Control';
            app.TrajectoriesasafunctionoftimePanel_2 = uipanel(app.ImpedanceControlTab);
            app.TrajectoriesasafunctionoftimePanel_2.Title = 'Trajectories as a function of time: ';
            app.XtEditField_2Label = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.XtEditField_2Label.Text = 'X(t)';
            app.XtEditField_2 = uieditfield(app.TrajectoriesasafunctionoftimePanel_2, 'text');
            app.XtEditField_2.Value = '0.5*cos(3.14*t)';
            app.YtEditField_2Label = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.YtEditField_2Label.Text = 'Y(t)';
            app.YtEditField_2 = uieditfield(app.TrajectoriesasafunctionoftimePanel_2, 'text');
            app.YtEditField_2.Value = '0.5*sin(3.14*t)';
            app.ZtEditField_2Label = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.ZtEditField_2Label.Text = 'Z(t)';
            app.ZtEditField_2 = uieditfield(app.TrajectoriesasafunctionoftimePanel_2, 'text');
            app.ZtEditField_2.Value = '0';
            app.ThetatEditField_2Label = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.ThetatEditField_2Label.Text = 'Theta(t)';
            app.ThetatEditField_2 = uieditfield(app.TrajectoriesasafunctionoftimePanel_2, 'text');
            app.ThetatEditField_2.Value = '0';
            app.PhitEditField_2Label = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.PhitEditField_2Label.Text = 'Phi(t)';
            app.PhitEditField_2 = uieditfield(app.TrajectoriesasafunctionoftimePanel_2, 'text');
            app.PhitEditField_2.Value = '0';
            app.PsitEditField_2Label = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.PsitEditField_2Label.Text = 'Psi(t)';
            app.PsitEditField_2 = uieditfield(app.TrajectoriesasafunctionoftimePanel_2, 'text');
            app.PsitEditField_2.Value = '0';
            app.PleaseprovideposeintheRPYschemeLabel = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.PleaseprovideposeintheRPYschemeLabel.Text = 'Please provide pose in the RPY scheme';
            app.StopTimeEditField_2Label = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.StopTimeEditField_2Label.Text = 'Stop Time';
            app.StopTimeEditField_2 = uieditfield(app.TrajectoriesasafunctionoftimePanel_2, 'numeric');
            app.StopTimeEditField_2.Value = 10;
            app.ActivateXCheckBox_2 = uicheckbox(app.TrajectoriesasafunctionoftimePanel_2);
            app.ActivateXCheckBox_2.Text = 'Activate X';
            app.ActivateXCheckBox_2.Value = true;
            app.ActivateYCheckBox_2 = uicheckbox(app.TrajectoriesasafunctionoftimePanel_2);
            app.ActivateYCheckBox_2.Text = 'Activate Y';
            app.ActivateYCheckBox_2.Value = true;
            app.ActivateZCheckBox_2 = uicheckbox(app.TrajectoriesasafunctionoftimePanel_2);
            app.ActivateZCheckBox_2.Text = 'Activate Z';
            app.ActivateThetaCheckBox_2 = uicheckbox(app.TrajectoriesasafunctionoftimePanel_2);
            app.ActivateThetaCheckBox_2.Text = 'Activate Theta';
            app.ActivatePhiCheckBox_2 = uicheckbox(app.TrajectoriesasafunctionoftimePanel_2);
            app.ActivatePhiCheckBox_2.Text = 'Activate Phi';
            app.ActivatePsiCheckBox_2 = uicheckbox(app.TrajectoriesasafunctionoftimePanel_2);
            app.ActivatePsiCheckBox_2.Text = 'Activate Psi';
            app.NoteSystemmaynotconvergeiftherobotLabel = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.NoteSystemmaynotconvergeiftherobotLabel.Text = 'Note: System may not converge if the robot';
            app.encountersasingularityLabel = uilabel(app.TrajectoriesasafunctionoftimePanel_2);
            app.encountersasingularityLabel.Text = ' encounters a singularity';
            app.FxtEditField_2Label = uilabel(app.ImpedanceControlTab);
            app.FxtEditField_2Label.Text = 'Fx(t)';
            app.FxtEditField_2 = uieditfield(app.ImpedanceControlTab, 'text');
            app.FxtEditField_2.Value = '.25*t+cos(3*t)';
            app.FytEditField_2Label = uilabel(app.ImpedanceControlTab);
            app.FytEditField_2Label.Text = 'Fy(t)';
            app.FytEditField_2 = uieditfield(app.ImpedanceControlTab, 'text');
            app.FytEditField_2.Value = '-.25*t+cos(3*t)';
            app.FztEditField_2Label = uilabel(app.ImpedanceControlTab);
            app.FztEditField_2Label.Text = 'Fz(t)';
            app.FztEditField_2 = uieditfield(app.ImpedanceControlTab, 'text');
            app.FztEditField_2.Value = '0';
            app.FPhitEditField_2Label = uilabel(app.ImpedanceControlTab);
            app.FPhitEditField_2Label.Text = 'FPhi(t)';
            app.FPhitEditField_2 = uieditfield(app.ImpedanceControlTab, 'text');
            app.FPhitEditField_2.Value = '0';
            app.FThetatEditField_2Label = uilabel(app.ImpedanceControlTab);
            app.FThetatEditField_2Label.Text = 'FTheta(t)';
            app.FThetatEditField_2 = uieditfield(app.ImpedanceControlTab, 'text');
            app.FThetatEditField_2.Value = '0';
            app.Label = uilabel(app.ImpedanceControlTab);
            app.Label.Text = '';
            app.GainsPanel = uipanel(app.ImpedanceControlTab);
            app.GainsPanel.Title = 'Gains';
            app.KpxEditField_2Label = uilabel(app.GainsPanel);
            app.KpxEditField_2Label.Text = 'Kp x';
            app.KpxEditField_2 = uieditfield(app.GainsPanel, 'numeric');
            app.KpxEditField_2.Value = 50;
            app.KpyEditField_2Label = uilabel(app.GainsPanel);
            app.KpyEditField_2Label.Text = 'Kp y';
            app.KpyEditField_2 = uieditfield(app.GainsPanel, 'numeric');
            app.KpyEditField_2.Value = 50;
            app.KpzEditField_2Label = uilabel(app.GainsPanel);
            app.KpzEditField_2Label.Text = 'Kp z';
            app.KpzEditField_2 = uieditfield(app.GainsPanel, 'numeric');
            app.KpzEditField_2.Value = 1;
            app.KpPhiEditFieldLabel = uilabel(app.GainsPanel);
            app.KpPhiEditFieldLabel.Text = 'Kp Phi';
            app.KpPhiEditField = uieditfield(app.GainsPanel, 'numeric');
            app.KpPhiEditField.Value = 1;
            app.KpThetaEditFieldLabel = uilabel(app.GainsPanel);
            app.KpThetaEditFieldLabel.Text = 'Kp Theta';
            app.KpThetaEditField = uieditfield(app.GainsPanel, 'numeric');
            app.KpThetaEditField.Value = 1;
            app.KpPsiEditFieldLabel = uilabel(app.GainsPanel);
            app.KpPsiEditFieldLabel.Text = 'Kp Psi';
            app.KpPsiEditField = uieditfield(app.GainsPanel, 'numeric');
            app.KpPsiEditField.Value = 1;
            app.KdxEditField_2Label = uilabel(app.GainsPanel);
            app.KdxEditField_2Label.Text = 'Kd x';
            app.KdxEditField_2 = uieditfield(app.GainsPanel, 'numeric');
            app.KdxEditField_2.Value = 10;
            app.KdyEditField_2Label = uilabel(app.GainsPanel);
            app.KdyEditField_2Label.Text = 'Kd y';
            app.KdyEditField_2 = uieditfield(app.GainsPanel, 'numeric');
            app.KdyEditField_2.Value = 10;
            app.KdzEditField_2Label = uilabel(app.GainsPanel);
            app.KdzEditField_2Label.Text = 'Kd z';
            app.KdzEditField_2 = uieditfield(app.GainsPanel, 'numeric');
            app.KdzEditField_2.Value = 1;
            app.KdPhiEditFieldLabel = uilabel(app.GainsPanel);
            app.KdPhiEditFieldLabel.Text = 'Kd Phi';
            app.KdPhiEditField = uieditfield(app.GainsPanel, 'numeric');
            app.KdPhiEditField.Value = 1;
            app.KdThetaEditFieldLabel = uilabel(app.GainsPanel);
            app.KdThetaEditFieldLabel.Text = 'Kd Theta';
            app.KdThetaEditField = uieditfield(app.GainsPanel, 'numeric');
            app.KdThetaEditField.Value = 1;
            app.KdPsiEditFieldLabel = uilabel(app.GainsPanel);
            app.KdPsiEditFieldLabel.Text = 'Kd Psi';
            app.KdPsiEditField = uieditfield(app.GainsPanel, 'numeric');
            app.KdPsiEditField.Value = 1;
            app.MdxEditFieldLabel = uilabel(app.GainsPanel);
            app.MdxEditFieldLabel.Text = 'Md x';
            app.MdxEditField = uieditfield(app.GainsPanel, 'numeric');
            app.MdxEditField.Value = 0.5;
            app.MdyEditFieldLabel = uilabel(app.GainsPanel);
            app.MdyEditFieldLabel.Text = 'Md y';
            app.MdyEditField = uieditfield(app.GainsPanel, 'numeric');
            app.MdyEditField.Value = 0.5;
            app.MdzEditFieldLabel = uilabel(app.GainsPanel);
            app.MdzEditFieldLabel.Text = 'Md z';
            app.MdzEditField = uieditfield(app.GainsPanel, 'numeric');
            app.MdzEditField.Value = 1;
            app.MdPhiEditFieldLabel = uilabel(app.GainsPanel);
            app.MdPhiEditFieldLabel.Text = 'Md Phi';
            app.MdPhiEditField = uieditfield(app.GainsPanel, 'numeric');
            app.MdPhiEditField.Value = 1;
            app.MdThetaEditFieldLabel = uilabel(app.GainsPanel);
            app.MdThetaEditFieldLabel.Text = 'Md Theta';
            app.MdThetaEditField = uieditfield(app.GainsPanel, 'numeric');
            app.MdThetaEditField.Value = 1;
            app.MdPsiEditFieldLabel = uilabel(app.GainsPanel);
            app.MdPsiEditFieldLabel.Text = 'Md Psi';
            app.MdPsiEditField = uieditfield(app.GainsPanel, 'numeric');
            app.MdPsiEditField.Value = 1;
            app.FPsitEditField_2Label = uilabel(app.ImpedanceControlTab);
            app.FPsitEditField_2Label.Text = 'FPsi(t)';
            app.FPsitEditField_2 = uieditfield(app.ImpedanceControlTab, 'text');
            app.FPsitEditField_2.Value = '0';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Hyperlink = uihyperlink(app.HelpTab);
            app.Hyperlink.Text = 'Link to Documentation and How to';
            app.TextArea_3 = uitextarea(app.HelpTab);
            app.TextArea_3.Value = {'Last Revision: 4/24/2024'; ''; 'Credits:'; ''; 'GUI: Eric Weissman'; 'EoMs: Eric Weissman'; 'Joint Dynamics: Eric Weissman'; 'Compliance Control: Eric Weissman'; 'Impedence Control: Eric Weissman'; 'Documentation and How to: '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboticsAppGUIV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end